classdef GielisMetaCST < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GielisMetaCSTPanel            matlab.ui.container.Panel
        ErSpinner                     matlab.ui.control.Spinner
        ErSpinnerLabel                matlab.ui.control.Label
        LinewidthmmSpinner            matlab.ui.control.Spinner
        LinewidthmmSpinnerLabel       matlab.ui.control.Label
        SimulateandpostprocessButton  matlab.ui.control.Button
        ConcentricstructurePanel      matlab.ui.container.Panel
        Size2Spinner                  matlab.ui.control.Spinner
        Size2SpinnerLabel             matlab.ui.control.Label
        Size1Spinner                  matlab.ui.control.Spinner
        Size1SpinnerLabel             matlab.ui.control.Label
        dymmSpinner                   matlab.ui.control.Spinner
        dymmSpinnerLabel              matlab.ui.control.Label
        dxmmSpinner                   matlab.ui.control.Spinner
        dxmmSpinnerLabel              matlab.ui.control.Label
        RotationSlider                matlab.ui.control.Slider
        RotationSliderLabel           matlab.ui.control.Label
        UIAxes_2                      matlab.ui.control.UIAxes
        SimulationchecklistPanel      matlab.ui.container.Panel
        ResultdefinedCheckBox         matlab.ui.control.CheckBox
        BoundariesconfiguredCheckBox  matlab.ui.control.CheckBox
        PortsdefinedCheckBox          matlab.ui.control.CheckBox
        MaterialsassignedCheckBox     matlab.ui.control.CheckBox
        GeometrygeneratedCheckBox     matlab.ui.control.CheckBox
        ModeconfigurationPanel        matlab.ui.container.Panel
        Label                         matlab.ui.control.Label
        DropDown_4                    matlab.ui.control.DropDown
        RangodefrecuenciaSpinnerLabel_4  matlab.ui.control.Label
        DropDown_3                    matlab.ui.control.DropDown
        RangodefrecuenciaSpinnerLabel_3  matlab.ui.control.Label
        DropDown_2                    matlab.ui.control.DropDown
        RangodefrecuenciaSpinnerLabel_2  matlab.ui.control.Label
        DropDown                      matlab.ui.control.DropDown
        RangodefrecuenciaSpinner_2    matlab.ui.control.Spinner
        FrequencyrangeSpinner         matlab.ui.control.Spinner
        RangodefrecuenciaLabel        matlab.ui.control.Label
        ButtonGroup_3                 matlab.ui.container.ButtonGroup
        SensorButton                  matlab.ui.control.RadioButton
        TransmitterButton             matlab.ui.control.RadioButton
        AbsorberButton                matlab.ui.control.RadioButton
        GieliscurvepreviewPanel       matlab.ui.container.Panel
        UIAxes                        matlab.ui.control.UIAxes
        Panel                         matlab.ui.container.Panel
        DielctricoDropDownLabel_2     matlab.ui.control.Label
        ButtonGroup_2                 matlab.ui.container.ButtonGroup
        ComplementaryButton           matlab.ui.control.RadioButton
        NormalButton                  matlab.ui.control.RadioButton
        CSTmaterialsPanel             matlab.ui.container.Panel
        dielectrictanSpinner          matlab.ui.control.Spinner
        dielectrictanSpinnerLabel     matlab.ui.control.Label
        DielectricDropDown            matlab.ui.control.DropDown
        DielectricDropDownLabel       matlab.ui.control.Label
        ConductorDropDown             matlab.ui.control.DropDown
        ConductorDropDownLabel        matlab.ui.control.Label
        BuildmodelinCSTButton         matlab.ui.control.Button
        GeneratestructureButton       matlab.ui.control.Button
        PreviewButton                 matlab.ui.control.Button
        GielisgeometryPanel           matlab.ui.container.Panel
        n3Spinner                     matlab.ui.control.Spinner
        n3SpinnerLabel                matlab.ui.control.Label
        n2Spinner                     matlab.ui.control.Spinner
        n2SpinnerLabel                matlab.ui.control.Label
        n1Spinner                     matlab.ui.control.Spinner
        n1SpinnerLabel                matlab.ui.control.Label
        mSpinner                      matlab.ui.control.Spinner
        mSpinnerLabel                 matlab.ui.control.Label
        bSpinner                      matlab.ui.control.Spinner
        bSpinnerLabel                 matlab.ui.control.Label
        aSpinner                      matlab.ui.control.Spinner
        aSpinnerLabel                 matlab.ui.control.Label
        SubstrateandmetalPanel        matlab.ui.container.Panel
        gmmSpinner                    matlab.ui.control.Spinner
        gmmSpinnerLabel               matlab.ui.control.Label
        h1coppermmSpinner             matlab.ui.control.Spinner
        h1coppermmSpinnerLabel        matlab.ui.control.Label
        hsubstratemmSpinner           matlab.ui.control.Spinner
        hsubstratemmSpinnerLabel      matlab.ui.control.Label
        WsubstratemmSpinner           matlab.ui.control.Spinner
        WsubstratemmSpinnerLabel      matlab.ui.control.Label
        LsubstratemmSpinner           matlab.ui.control.Spinner
        LsubstratemmSpinnerLabel      matlab.ui.control.Label
    end

    properties (Access = public)
        CSTActual
    rutaCSTActual
    nombreProyectoCST
    anguloRotacionVista double = 0
    modeloCSTConstruido logical = false
        xx_original
    yy_original
    contornosExternos
    contornosHuecos
    pgGielis
    pgGielisExterior
    contornosConcentricos
    contornosConcentricosBase
    pgConcentricos
    escalaFigura1
    escalaFigura2
        xx
        yy
        x
        y
        z
        x2
        y2
        z2
        x3
        y3
        z3
        x4
        y4
        z4
        P1
        P2
        xp3
        g
        rho
        theta
        yp4
        xpp3
        ypp4
        xppp3
        yppp4
        yp1
        yp2
        yp3
        PP1
        PP2
        PP3
        PP4
        W
        L
        Er
        Pxp3
        Pyp4
        Pxppp3
        Pyppp4% Description
    end
    
methods (Access = private)

    function [fCampo, tipoCampo, ok] = pedirMonitorCampoPostSim(app, fSugerida, resumenTexto)

    ok = false;
    fCampo = [];
    tipoCampo = "";

    fmin = app.FrequencyrangeSpinner.Value;
    fmax = app.RangodefrecuenciaSpinner_2.Value;
    unidad = string(app.DropDown.Value);

    if nargin < 2 || isempty(fSugerida) || ~isfinite(fSugerida)
        fSugerida = (fmin + fmax)/2;
    end

    if nargin < 3 || strlength(string(resumenTexto)) == 0
        resumenTexto = "Simulation completed.";
    end

    mensaje = string(resumenTexto) + newline + newline + ...
        "Do you want to generate field monitors in CST?";

    opcion = uiconfirm(app.UIFigure, ...
        mensaje, ...
        "Field monitors", ...
        "Options", {'Do not generate fields', ...
                    'Electric field', ...
                    'Electric and magnetic field'}, ...
        "DefaultOption", 'Do not generate fields', ...
        "CancelOption", 'Do not generate fields');

    if strcmp(opcion, 'Do not generate fields')
        return;
    end

    respuesta = inputdlg( ...
        {"Field monitor frequency (" + unidad + "):"}, ...
        "Monitor frequency", ...
        [1 55], ...
        {num2str(fSugerida)});

    if isempty(respuesta)
        return;
    end

    fCampo = str2double(respuesta{1});

    if isnan(fCampo) || fCampo < fmin || fCampo > fmax
        uialert(app.UIFigure, ...
            "The frequency must be within the simulation range: " + ...
            string(fmin) + " - " + string(fmax) + " " + unidad, ...
            "Invalid frequency");
        return;
    end

    if strcmp(opcion, 'Electric field')
        tipoCampo = "E";
    elseif strcmp(opcion, 'Electric and magnetic field')
        tipoCampo = "EH";
    end

    ok = true;

end

function agregarMonitoresCampoYReSimular(app, CST, fCampo, tipoCampo)

    if isempty(CST)
        uialert(app.UIFigure, ...
            "There is no active CST session. Build and simulate the model first.", ...
            "CST not available");
        return;
    end

    try

        switch string(tipoCampo)

            case "E"

                CST.addFieldMonitor('Efield', fCampo);

            case "EH"

                CST.addFieldMonitor('Efield', fCampo);
                CST.addFieldMonitor('Hfield', fCampo);

            otherwise

                uialert(app.UIFigure, ...
                    "Unrecognized monitor type.", ...
                    "Monitor error");
                return;

        end

        app.ResultdefinedCheckBox.Value = true;

        CST.runSimulation();
        app.setChecklist(true, true, true, true, true, true);

        uialert(app.UIFigure, ...
            "Field monitor successfully generated." + newline + ...
            "Frequency: " + string(fCampo) + " " + string(app.DropDown.Value) + newline + ...
            "Type: " + string(tipoCampo) + newline + newline + ...
            "The simulation was run again to save the fields.", ...
            "Fields generated");

    catch ME

        uialert(app.UIFigure, ...
            "The field monitors could not be generated." + newline + newline + ...
            string(ME.message), ...
            "CST error");

        disp(getReport(ME, 'extended'));

    end

end
function [isValid, msg] = validateGapBeforeGenerate(app)

    isValid = true;
    msg = "";

    g = app.gmmSpinner.Value;

    if isempty(g) || ~isfinite(g)
        isValid = false;
        msg = "The g value must be a valid number.";
        return;
    end

    if g <= 0
        isValid = false;
        msg = "The g value must be greater than zero.";
        return;
    end

end

function markStateAfterGenerate(app)

    % ==========================================================
    % State after Generate
    % ==========================================================
    % Generate only creates the geometry inside MATLAB.
    % Materials, ports, boundaries and CST results are not defined yet.

    app.CSTActual = [];
    app.rutaCSTActual = "";
    app.nombreProyectoCST = "";
    app.modeloCSTConstruido = false;

    app.GeometrygeneratedCheckBox.Value = true;
    app.MaterialsassignedCheckBox.Value = false;
    app.PortsdefinedCheckBox.Value = false;
    app.BoundariesconfiguredCheckBox.Value = false;
    app.ResultdefinedCheckBox.Value = false;

    app.SimulateandpostprocessButton.Enable = 'off';

    drawnow limitrate;

end
function markStateAfterBuild(app)
    app.GeometrygeneratedCheckBox.Value = true;
    app.MaterialsassignedCheckBox.Value = true;
    app.PortsdefinedCheckBox.Value = true;
    app.BoundariesconfiguredCheckBox.Value = true;
    app.ResultdefinedCheckBox.Value = true;

    app.modeloCSTConstruido = true;
    app.SimulateandpostprocessButton.Enable = 'on';

    drawnow limitrate;

end


    function Crot = rotarContornoVisual(app, C, angDeg)
    Crot = [];
    if isempty(C)
        return;
    end

    C = C(:,1:2);
    theta = deg2rad(angDeg);

    R = [cos(theta) -sin(theta);
         sin(theta)  cos(theta)];

    puntosRotados = C * R.';

    Crot = puntosRotados;

    end

    function parametrosChanged(app, event)

    app.invalidarModeloCST();

    end

    function [Cmain, pgMain] = getOriginalContourForCST(app, x, y)

    Cmain = [];
    pgMain = polyshape();

    x = x(:);
    y = y(:);

    valid = isfinite(x) & isfinite(y);
    x = x(valid);
    y = y(valid);

    if numel(x) < 3
        return;
    end

    C0 = [x y];

    % Eliminar puntos duplicados consecutivos
    d = sqrt(sum(diff(C0).^2, 2));
    keep = [true; d > 1e-9];
    C0 = C0(keep,:);

    if size(C0,1) < 3
        return;
    end

    % Cerrar contorno si no está cerrado
    if hypot(C0(1,1)-C0(end,1), C0(1,2)-C0(end,2)) > 1e-9
        C0(end+1,:) = C0(1,:);
    end

    % ==========================================================
    % Convertir la curva paramétrica en una región válida
    % Esta etapa resuelve autointersecciones.
    % ==========================================================
    try
        pg0 = polyshape(C0(:,1), C0(:,2), ...
            'Simplify', true, ...
            'KeepCollinearPoints', false);
    catch
        return;
    end

    if area(pg0) <= 0
        return;
    end

    % Eliminar huecos internos si aparecen
    try
        pg0 = rmholes(pg0);
    catch
        % Si la versión de MATLAB no soporta rmholes, continuar
    end

    % Extraer solo la frontera principal
    Cmain = app.getMainContourFromPolyshape(pg0);

    if isempty(Cmain) || size(Cmain,1) < 3
        return;
    end

    % Crear polyshape final válido
    pgMain = polyshape(Cmain(:,1), Cmain(:,2), ...
        'Simplify', true, ...
        'KeepCollinearPoints', false);

    if area(pgMain) <= 0
        Cmain = [];
        pgMain = polyshape();
    end

    end

    function [Cbase, pgBase, metodo] = seleccionarContornoBaseParaCST(app, xOrig, yOrig)

    % ==========================================================
    % Usar siempre una envolvente radial de complejidad controlada
    % ==========================================================
    % Esto reproduce el comportamiento del código anterior,
    % que construye rápidamente las geometrías en CST.

    nAng = 720;
    smoothWindow = 5;

    [Cbase, pgBase] = app.getRadialOuterEnvelope( ...
        xOrig, ...
        yOrig, ...
        nAng, ...
        smoothWindow);

    metodo = "Radial external envelope";

    if isempty(Cbase) || area(pgBase) <= 0

        Cbase = [];
        pgBase = polyshape();
        metodo = "None";

        uialert(app.UIFigure, ...
            "A valid radial envelope could not be generated.", ...
            "Invalid geometry");

    end

end

    function actualizarControlesMaterial(app)

    diel = lower(strtrim(string(app.DielectricDropDown.Value)));

    switch diel

        case "fr4_lossy"

            app.ErSpinner.Value = 4.3;
            app.dielectrictanSpinner.Value = 0.02;

            app.ErSpinner.Enable = 'off';
            app.dielectrictanSpinner.Enable = 'off';

        case "rogers_ro4003c"

            app.ErSpinner.Value = 3.55;
            app.dielectrictanSpinner.Value = 0.0027;

            app.ErSpinner.Enable = 'off';
            app.dielectrictanSpinner.Enable = 'off';

        case "rogers_ro5880"

            app.ErSpinner.Value = 2.2;
            app.dielectrictanSpinner.Value = 0.0009;

            app.ErSpinner.Enable = 'off';
            app.dielectrictanSpinner.Enable = 'off';

        case "alumina"

            app.ErSpinner.Value = 9.8;
            app.dielectrictanSpinner.Value = 0.0001;

            app.ErSpinner.Enable = 'off';
            app.dielectrictanSpinner.Enable = 'off';

        case {"custom_er", "personalizado_er"}

            app.ErSpinner.Enable = 'on';
            app.dielectrictanSpinner.Enable = 'on';

            if app.ErSpinner.Value <= 0
                app.ErSpinner.Value = 4.3;
            end

            if app.dielectrictanSpinner.Value < 0
                app.dielectrictanSpinner.Value = 0.02;
            end

        otherwise

            app.ErSpinner.Enable = 'on';
            app.dielectrictanSpinner.Enable = 'on';

    end

    app.invalidarModeloCST();

end

    function hay = hayGeometriaCSTLista(app)

    hay = ...
        ~isempty(app.contornosConcentricosBase) && ...
        numel(app.contornosConcentricosBase) >= 4;

    if ~hay
        hay = ...
            ~isempty(app.contornosConcentricos) && ...
            numel(app.contornosConcentricos) >= 4;
    end

    end

    function setChecklist(app, hayGeometria, hayMateriales, hayPuertos, hayFronteras, hayMonitor, modeloListo)

    app.GeometrygeneratedCheckBox.Value = logical(hayGeometria);
    app.MaterialsassignedCheckBox.Value = logical(hayMateriales);
    app.PortsdefinedCheckBox.Value = logical(hayPuertos);
    app.BoundariesconfiguredCheckBox.Value = logical(hayFronteras);
    app.ResultdefinedCheckBox.Value = logical(hayMonitor);

    app.modeloCSTConstruido = logical(modeloListo);

    if modeloListo
        app.SimulateandpostprocessButton.Enable = 'on';
    else
        app.SimulateandpostprocessButton.Enable = 'off';
    end

    drawnow limitrate;

end

    function invalidarModeloCST(app)

    app.CSTActual = [];
    app.rutaCSTActual = "";
    app.nombreProyectoCST = "";

    hayGeo = app.hayGeometriaCSTLista();

    app.setChecklist( ...
        hayGeo, ...
        false, ...
        false, ...
        false, ...
        false, ...
        false);

end

function [Cenv, pgEnv] = getRadialOuterEnvelope(app, x, y, nAng, smoothWindow)

    % ==========================================================
    % Initialize outputs
    % ==========================================================
    Cenv = [];
    pgEnv = polyshape();

    % Convert coordinates to column vectors
    x = x(:);
    y = y(:);

    % Remove invalid points
    valid = isfinite(x) & isfinite(y);
    x = x(valid);
    y = y(valid);

    if numel(x) < 3
        return;
    end

    % ==========================================================
    % Reference center
    % ==========================================================
    xc = 0;
    yc = 0;

    % Polar coordinates of the original points
    phi = atan2(y - yc, x - xc);
    phi = mod(phi, 2*pi);

    r = hypot(x - xc, y - yc);

    % ==========================================================
    % Default parameters
    % ==========================================================
    if nargin < 4 || isempty(nAng)
        nAng = 720;
    end

    if nargin < 5 || isempty(smoothWindow)
        smoothWindow = 5;
    end

    nAng = max(90, round(nAng));
    smoothWindow = max(1, round(smoothWindow));

    % Smoothing window must be odd
    if mod(smoothWindow, 2) == 0
        smoothWindow = smoothWindow + 1;
    end

    % ==========================================================
    % Divide the complete angular range into bins
    % ==========================================================
    edges = linspace(0, 2*pi, nAng + 1);

    [~, ~, bin] = histcounts(phi, edges);

    validBin = bin > 0 & isfinite(r);

    if nnz(validBin) < 3
        return;
    end

    % Maximum radius inside each angular bin
    rMax = accumarray( ...
        bin(validBin), ...
        r(validBin), ...
        [nAng 1], ...
        @max, ...
        NaN);

    idxValid = find(~isnan(rMax));

    if numel(idxValid) < 3
        return;
    end

    % ==========================================================
    % Circular interpolation of missing angular bins
    % ==========================================================
    idxAll = (1:nAng).';

    idxExt = [ ...
        idxValid - nAng; ...
        idxValid; ...
        idxValid + nAng];

    rExt = [ ...
        rMax(idxValid); ...
        rMax(idxValid); ...
        rMax(idxValid)];

    rFilled = interp1( ...
        idxExt, ...
        rExt, ...
        idxAll, ...
        'linear');

    if any(isnan(rFilled))

        rFilled = interp1( ...
            idxExt, ...
            rExt, ...
            idxAll, ...
            'nearest', ...
            'extrap');

    end

    % ==========================================================
    % Circular smoothing
    % ==========================================================
    if smoothWindow > 1

        pad = floor(smoothWindow/2);

        rPad = [ ...
            rFilled(end-pad+1:end); ...
            rFilled; ...
            rFilled(1:pad)];

        kernel = ones(smoothWindow, 1) / smoothWindow;

        rSmoothPad = conv( ...
            rPad, ...
            kernel, ...
            'same');

        rSmooth = rSmoothPad( ...
            pad + 1 : ...
            pad + nAng);

    else

        rSmooth = rFilled;

    end

    % ==========================================================
    % Convert radial envelope back to Cartesian coordinates
    % ==========================================================
    phiGrid = 0.5 * ( ...
        edges(1:end-1) + ...
        edges(2:end)).';

    xEnv = xc + rSmooth .* cos(phiGrid);
    yEnv = yc + rSmooth .* sin(phiGrid);

    % Close contour
    xEnv(end+1) = xEnv(1);
    yEnv(end+1) = yEnv(1);

    Cenv = [xEnv yEnv];

    % ==========================================================
    % Create final polyshape
    % ==========================================================
    try

        pgEnv = polyshape( ...
            Cenv(:,1), ...
            Cenv(:,2), ...
            'Simplify', true, ...
            'KeepCollinearPoints', false);

    catch

        Cenv = [];
        pgEnv = polyshape();
        return;

    end

    if area(pgEnv) <= 0
        Cenv = [];
        pgEnv = polyshape();
    end

end

    function Cmain = getMainContourFromPolyshape(app, pg)

        Cmain = [];

        if isempty(pg) || area(pg) <= 0
            return;
        end

        [x, y] = boundary(pg);

        x = x(:);
        y = y(:);

        nanIdx = isnan(x) | isnan(y);
        cuts = [0; find(nanIdx); numel(x)+1];

        contours = {};

        for k = 1:numel(cuts)-1

            idx = cuts(k)+1 : cuts(k+1)-1;

            if numel(idx) < 3
                continue;
            end

            C = [x(idx), y(idx)];

            if hypot(C(1,1)-C(end,1), C(1,2)-C(end,2)) > 1e-9
                C(end+1,:) = C(1,:);
            end

            contours{end+1} = C;
        end

        if isempty(contours)
            return;
        end

        areas = cellfun(@(C) abs(polyarea(C(:,1), C(:,2))), contours);
        [~, idxMax] = max(areas);

        Cmain = contours{idxMax};

    end

function actualizarContornosDesdeControles(app)

   Cbase = [];

    if ~isempty(app.contornosExternos) && numel(app.contornosExternos) >= 1
        Cbase = app.contornosExternos{1};
    elseif ~isempty(app.contornosConcentricosBase) && numel(app.contornosConcentricosBase) >= 1
        Cbase = app.contornosConcentricosBase{1};
    end

    if isempty(Cbase) || size(Cbase,1) < 3
        return;
    end

    Cbase = Cbase(:,1:2);

    validBase = isfinite(Cbase(:,1)) & isfinite(Cbase(:,2));
    Cbase = Cbase(validBase,:);

    if size(Cbase,1) < 3
        return;
    end

    % Remove repeated closing point if present
    if hypot(Cbase(1,1)-Cbase(end,1), Cbase(1,2)-Cbase(end,2)) < 1e-9
        Cbase(end,:) = [];
    end

    if size(Cbase,1) < 3
        return;
    end

    % ==========================================================
    % 2. Reference radius
    % ==========================================================
    Rbase = max(hypot(Cbase(:,1), Cbase(:,2)));

    if isempty(Rbase) || ~isfinite(Rbase) || Rbase <= 0

        uialert(app.UIFigure, ...
            "The base contour does not have a valid radius.", ...
            "Invalid geometry");

        return;

    end

    % ==========================================================
    % 3. Read controls
    % ==========================================================
    escala1 = app.Size1Spinner.Value;
    escala2 = app.Size2Spinner.Value;

    g = app.gmmSpinner.Value;

    dx = app.dxmmSpinner.Value;
    dy = app.dymmSpinner.Value;

    ang = app.anguloRotacionVista;

    minScale = 0.05;

    if isempty(escala1) || ~isfinite(escala1) || escala1 <= minScale
        escala1 = 1.00;
        app.Size1Spinner.Value = escala1;
    end

    if isempty(escala2) || ~isfinite(escala2) || escala2 <= minScale
        escala2 = 0.70;
        app.Size2Spinner.Value = escala2;
    end

    if isempty(g) || ~isfinite(g) || g <= 0
        g = 0.10;
        app.gmmSpinner.Value = g;
    end

    % ==========================================================
    % 4. Fixed physical gap construction
    % ==========================================================
    % Size 1 controls the outer size of ring 1.
    % Size 2 controls the outer size of ring 2.
    %
    % The inner contour of each ring is computed by subtracting
    % the physical gap g from the maximum radius.
    % ==========================================================

     interRingOffsetFactor = 0.10;
    stepSize = 0.10;

    scale1Outer = escala1;
    scale1Inner = escala1 - g/Rbase;

    if scale1Inner <= minScale
        uialert(app.UIFigure, ...
            "The g value is too large for Ring 1." + newline + ...
            "Increase Size 1 or reduce g.", ...
            "Invalid Ring 1 gap");
        return;
    end

    interRingOffset = interRingOffsetFactor * g;

    maxScale2Outer = scale1Inner - interRingOffset/Rbase;
    minScale2Outer = minScale + g/Rbase;

    if maxScale2Outer <= minScale2Outer

        gOld = g;

        gMax = (escala1 - 2*minScale) * Rbase / ...
               (2 + interRingOffsetFactor);

        g = 0.98 * gMax;
        app.gmmSpinner.Value = g;

        interRingOffset = interRingOffsetFactor * g;

        scale1Inner = escala1 - g/Rbase;
        maxScale2Outer = scale1Inner - interRingOffset/Rbase;
        minScale2Outer = minScale + g/Rbase;

        disp("g adjusted only because Ring 2 would touch Ring 1.");
        disp("Original g = " + string(gOld));
        disp("Adjusted g = " + string(g));

    end

    if escala2 < minScale2Outer

        escala2Old = escala2;

        escala2 = ceil(minScale2Outer / stepSize) * stepSize;

        if escala2 > maxScale2Outer
            escala2 = minScale2Outer;
        end

        app.Size2Spinner.Value = escala2;

        disp("Size 2 increased automatically.");
        disp("Original Size 2 = " + string(escala2Old));
        disp("Adjusted Size 2 = " + string(escala2));

    end

    if escala2 > maxScale2Outer

        escala2Old = escala2;

        escala2 = floor(maxScale2Outer / stepSize) * stepSize;

        if escala2 < minScale2Outer
            escala2 = maxScale2Outer;
        end

        app.Size2Spinner.Value = escala2;

        disp("Size 2 reduced automatically.");
        disp("Original Size 2 = " + string(escala2Old));
        disp("Adjusted Size 2 = " + string(escala2));

    end

    scale2Outer = escala2;
    scale2Inner = escala2 - g/Rbase;

    if scale2Inner <= minScale
        escala2Old = escala2;
        escala2 = minScale2Outer;
        app.Size2Spinner.Value = escala2;

        scale2Outer = escala2;
        scale2Inner = escala2 - g/Rbase;

        disp("Size 2 corrected to preserve Ring 2 gap.");
        disp("Original Size 2 = " + string(escala2Old));
        disp("Corrected Size 2 = " + string(escala2));
    end

    % ==========================================================
    % 6. Build the four final contours
    % ==========================================================
    C1 = app.scaleContour(Cbase, scale1Outer);
    C2 = app.scaleContour(Cbase, scale1Inner);

    C3 = app.scaleContour(Cbase, scale2Outer);
    C4 = app.scaleContour(Cbase, scale2Inner);

    % ==========================================================
    % 7. Validate nesting using areas
    % ==========================================================
    pg1 = polyshape(C1(:,1), C1(:,2), ...
        'Simplify', true, ...
        'KeepCollinearPoints', false);

    pg2 = polyshape(C2(:,1), C2(:,2), ...
        'Simplify', true, ...
        'KeepCollinearPoints', false);

    pg3 = polyshape(C3(:,1), C3(:,2), ...
        'Simplify', true, ...
        'KeepCollinearPoints', false);

    pg4 = polyshape(C4(:,1), C4(:,2), ...
        'Simplify', true, ...
        'KeepCollinearPoints', false);

    if area(pg1) <= 0 || area(pg2) <= 0 || ...
       area(pg3) <= 0 || area(pg4) <= 0

        uialert(app.UIFigure, ...
            "The generated concentric contours are not valid.", ...
            "Invalid concentric geometry");

        return;

    end

    if area(pg2) >= area(pg1) || ...
       area(pg3) >= area(pg2) || ...
       area(pg4) >= area(pg3)

        uialert(app.UIFigure, ...
            "The concentric contours are not correctly nested." + newline + newline + ...
            "Reduce g, reduce Size 2, or increase Size 1.", ...
            "Invalid concentric geometry");

        return;

    end

    % ==========================================================
    % 8. Apply rotation
    % ==========================================================
    C1 = app.rotarContornoVisual(C1, ang);
    C2 = app.rotarContornoVisual(C2, ang);
    C3 = app.rotarContornoVisual(C3, ang);
    C4 = app.rotarContornoVisual(C4, ang);

    % ==========================================================
    % 9. Apply centering and translation
    % ==========================================================
    modoActual = app.obtenerModoActual();

    if modoActual == "Sensor"

        % Center the Gielis-CSRR below the microstrip line.
        allX = [
            C1(:,1);
            C2(:,1);
            C3(:,1);
            C4(:,1)
        ];

        allY = [
            C1(:,2);
            C2(:,2);
            C3(:,2);
            C4(:,2)
        ];

        cx = (min(allX) + max(allX)) / 2;
        cy = (min(allY) + max(allY)) / 2;

        totalDx = -cx + dx;
        totalDy = -cy + dy;

    else

        totalDx = dx;
        totalDy = dy;

    end

    C1 = app.translateContour(C1, totalDx, totalDy);
    C2 = app.translateContour(C2, totalDx, totalDy);
    C3 = app.translateContour(C3, totalDx, totalDy);
    C4 = app.translateContour(C4, totalDx, totalDy);

    % ==========================================================
    % 10. Close contours if needed
    % ==========================================================
    if hypot(C1(1,1)-C1(end,1), C1(1,2)-C1(end,2)) > 1e-9
        C1(end+1,:) = C1(1,:);
    end

    if hypot(C2(1,1)-C2(end,1), C2(1,2)-C2(end,2)) > 1e-9
        C2(end+1,:) = C2(1,:);
    end

    if hypot(C3(1,1)-C3(end,1), C3(1,2)-C3(end,2)) > 1e-9
        C3(end+1,:) = C3(1,:);
    end

    if hypot(C4(1,1)-C4(end,1), C4(1,2)-C4(end,2)) > 1e-9
        C4(end+1,:) = C4(1,:);
    end

    % ==========================================================
    % 11. Store updated contours
    % ==========================================================
    app.contornosConcentricos = {C1, C2, C3, C4};

    app.escalaFigura1 = escala1;
    app.escalaFigura2 = escala2;

    % ==========================================================
    % 12. Debug information
    % ==========================================================
    disp("Fixed-gap Gielis-CSRR geometry updated.");
    disp("Physical g = " + string(g) + " mm");
    disp("Ring 1 outer scale = " + string(scale1Outer));
    disp("Ring 1 inner scale = " + string(scale1Inner));
    disp("Ring 2 outer scale = " + string(scale2Outer));
    disp("Ring 2 inner scale = " + string(scale2Inner));
    disp("Inter-ring offset = " + string(interRingOffset) + " mm");

end


    function points = prepareCSTPolygon(app, C)

        points = [];

        if isempty(C)
            return;
        end

        C = C(:,1:2);

        valid = isfinite(C(:,1)) & isfinite(C(:,2));
        C = C(valid,:);

        if size(C,1) < 3
            return;
        end

        d = sqrt(sum(diff(C).^2, 2));
        keep = [true; d > 1e-9];
        C = C(keep,:);

        if size(C,1) < 3
            return;
        end

        if hypot(C(1,1)-C(end,1), C(1,2)-C(end,2)) < 1e-9
            C(end,:) = [];
        end

        if size(C,1) < 3
            return;
        end

        points = C;

    end


    function Cscaled = scaleContour(app, C, scaleValue)

        Cscaled = [];

        if isempty(C)
            return;
        end

        Cscaled = C;
        Cscaled(:,1) = C(:,1) * scaleValue;
        Cscaled(:,2) = C(:,2) * scaleValue;

    end

    function Cs = smoothClosedContour(app, C, smoothWindow, nIter)

    Cs = [];

    if isempty(C)
        return;
    end

    C = C(:,1:2);

    valid = isfinite(C(:,1)) & isfinite(C(:,2));
    C = C(valid,:);

    if size(C,1) < 3
        return;
    end

    % Quitar punto repetido final si existe
    if hypot(C(1,1)-C(end,1), C(1,2)-C(end,2)) < 1e-9
        C(end,:) = [];
    end

    smoothWindow = max(3, round(smoothWindow));

    % La ventana debe ser impar
    if mod(smoothWindow, 2) == 0
        smoothWindow = smoothWindow + 1;
    end

    if nargin < 4 || isempty(nIter)
        nIter = 1;
    end

    x = C(:,1);
    y = C(:,2);

    pad = floor(smoothWindow/2);
    kernel = ones(smoothWindow, 1) / smoothWindow;

    for k = 1:nIter

        xPad = [x(end-pad+1:end); x; x(1:pad)];
        yPad = [y(end-pad+1:end); y; y(1:pad)];

        x = conv(xPad, kernel, 'valid');
        y = conv(yPad, kernel, 'valid');

    end

    Cs = [x y];

    % Cerrar contorno
    Cs(end+1,:) = Cs(1,:);

end


    function Cshift = translateContour(~, C, dx, dy)

        Cshift = [];

        if isempty(C)
            return;
        end

        Cshift = C;
        Cshift(:,1) = C(:,1) + dx;
        Cshift(:,2) = C(:,2) + dy;

    end


 function redrawConcentricContours3D(app)

    % ==========================================================
    % Validate geometry
    % ==========================================================
    if isempty(app.contornosConcentricos) || ...
       numel(app.contornosConcentricos) < 4
        return;
    end

    % ==========================================================
    % Read dimensions and controls
    % ==========================================================
    W = app.WsubstratemmSpinner.Value;
    L = app.LsubstratemmSpinner.Value;
    Z = app.hsubstratemmSpinner.Value;

    dx = app.dxmmSpinner.Value;
    dy = app.dymmSpinner.Value;
    ang = app.anguloRotacionVista;

    % ==========================================================
    % Read current contours
    % ==========================================================
    C1 = app.contornosConcentricos{1};
    C2 = app.contornosConcentricos{2};
    C3 = app.contornosConcentricos{3};
    C4 = app.contornosConcentricos{4};

    % ==========================================================
    % Validate final transformed resonator inside substrate
    % ==========================================================

    W = app.WsubstratemmSpinner.Value;
    L = app.LsubstratemmSpinner.Value;

    allPts = [
        C1(:,1:2);
        C2(:,1:2);
        C3(:,1:2);
        C4(:,1:2)
        ];

    validPts = isfinite(allPts(:,1)) & isfinite(allPts(:,2));
    allPts = allPts(validPts,:);
    % ==========================================================
    % Validate resonator inside substrate
    % ==========================================================

    substrateMargin = 0.10; % mm

    xminRes = min(allPts(:,1));
    xmaxRes = max(allPts(:,1));
    yminRes = min(allPts(:,2));
    ymaxRes = max(allPts(:,2));

    if xminRes < -W/2 + substrateMargin || xmaxRes > W/2 - substrateMargin || ...
            yminRes < -L/2 + substrateMargin || ymaxRes > L/2 - substrateMargin

        uialert(app.UIFigure, ...
            "The scaled resonator is outside the substrate." + newline + newline + ...
            "Current resonator limits:" + newline + ...
            "X = [" + string(xminRes) + ", " + string(xmaxRes) + "] mm" + newline + ...
            "Y = [" + string(yminRes) + ", " + string(ymaxRes) + "] mm" + newline + newline + ...
            "Increase W/L substrate or reduce Size 1 / Size 2.", ...
            "Resonator outside substrate");

        error("Scaled resonator is outside the substrate.");

    end

    substrateMargin = 0.05;

    if any(abs(allPts(:,1)) > W/2 - substrateMargin) || ...
            any(abs(allPts(:,2)) > L/2 - substrateMargin)

        uialert(app.UIFigure, ...
            "The transformed resonator is outside the substrate." + newline + newline + ...
            "Reduce Size 1 / Size 2, reduce dx/dy, or increase L/W substrate.", ...
            "Geometry outside substrate");

        return;

    end

    if isempty(C1) || isempty(C2) || ...
       isempty(C3) || isempty(C4)
        return;
    end

    % ==========================================================
    % Substrate visual geometry
    % ==========================================================
    x = [ ...
        -W/2, W/2, W/2, ...
        -W/2, -W/2, -W/2];

    y = [ ...
        -L/2, -L/2, -L/2, ...
        -L/2, L/2, L/2];

    z = [0, 0, Z, Z, Z, 0];

    x2 = [ ...
        W/2, -W/2, -W/2, ...
        W/2, W/2, W/2];

    y2 = [ ...
        L/2, L/2, L/2, ...
        L/2, -L/2, -L/2];

    z2 = [0, 0, Z, Z, Z, 0];

    x3 = [W/2, -W/2, -W/2, W/2];
    y3 = [L/2, L/2, -L/2, -L/2];
    z3 = [0, 0, 0, 0];

    x4 = [W/2, -W/2, -W/2, W/2];
    y4 = [L/2, L/2, -L/2, -L/2];
    z4 = [Z, Z, Z, Z];

    % ==========================================================
    % Prepare axes
    % ==========================================================
    ax2 = app.UIAxes_2;

    cla(ax2);
    hold(ax2, 'on');

    color_beige = [0.96, 0.86, 0.75];

    % ==========================================================
    % Draw substrate
    % ==========================================================
    patch(ax2, x, y, z, color_beige, ...
        'FaceAlpha', 0.5, ...
        'LineWidth', 1);

    patch(ax2, x2, y2, z2, color_beige, ...
        'FaceAlpha', 0.5, ...
        'LineWidth', 1);

    patch(ax2, x3, y3, z3, color_beige, ...
        'FaceAlpha', 0.5, ...
        'LineWidth', 1);

    patch(ax2, x4, y4, z4, color_beige, ...
        'FaceAlpha', 0.5, ...
        'LineWidth', 1);

    % ==========================================================
    % Current application mode
    % ==========================================================
    modoActual = app.obtenerModoActual();

    % ==========================================================
    % Draw microstrip line in Sensor mode
    % ==========================================================
    if modoActual == "Sensor"

        anchoLinea = app.obtenerAnchoLineaSensor();

        xLine = [-W/2, W/2, W/2, -W/2];

        yLine = [ ...
            -anchoLinea/2, ...
            -anchoLinea/2, ...
             anchoLinea/2, ...
             anchoLinea/2];

        zLine = ones(1,4) * (Z + 0.03);

        patch(ax2, ...
            xLine, ...
            yLine, ...
            zLine, ...
            [0.2 0.55 0.95], ...
            'FaceAlpha', 0.35, ...
            'EdgeColor', [0.05 0.20 0.60], ...
            'LineWidth', 1.5);

        text(ax2, ...
            -W/2 + 0.3, ...
            anchoLinea/2 + 0.35, ...
            Z + 0.08, ...
            sprintf('Line %.2f mm', anchoLinea), ...
            'FontSize', 9, ...
            'FontWeight', 'bold', ...
            'Color', [0.05 0.20 0.60]);

    end

    % ==========================================================
    % Draw concentric contours directly
    % ==========================================================
if modoActual == "Sensor"
    zContour = -0.04;
else
    zContour = Z + 0.04;
end

    plot3(ax2, ...
        C1(:,1), C1(:,2), ...
        ones(size(C1,1),1) * zContour, ...
        'k', 'LineWidth', 2);

    plot3(ax2, ...
        C2(:,1), C2(:,2), ...
        ones(size(C2,1),1) * zContour, ...
        'k', 'LineWidth', 2);

    plot3(ax2, ...
        C3(:,1), C3(:,2), ...
        ones(size(C3,1),1) * zContour, ...
        'k', 'LineWidth', 2);

    plot3(ax2, ...
        C4(:,1), C4(:,2), ...
        ones(size(C4,1),1) * zContour, ...
        'k', 'LineWidth', 2);

    hold(ax2, 'off');

    % ==========================================================
    % Axes configuration
    % ==========================================================
    axis(ax2, 'equal');
    grid(ax2, 'on');

    xlabel(ax2, 'X axis');
    ylabel(ax2, 'Y axis');
    zlabel(ax2, 'Z axis');

    title(ax2, sprintf( ...
        'Concentric structures: dx = %.3f, dy = %.3f, rot = %.1f°', ...
        dx, dy, ang));

    view(ax2, 2);

    % ==========================================================
    % Visualization limits
    % ==========================================================
    if modoActual == "Sensor"

        % Show the complete substrate and microstrip line
        marginX = max(0.5, 0.04 * W);
        marginY = max(0.5, 0.08 * L);

        xlim(ax2, [ ...
            -W/2 - marginX, ...
             W/2 + marginX]);

        ylim(ax2, [ ...
            -L/2 - marginY, ...
             L/2 + marginY]);

    else

        % Zoom around all four resonators
        allX = [ ...
            C1(:,1); ...
            C2(:,1); ...
            C3(:,1); ...
            C4(:,1)];

        allY = [ ...
            C1(:,2); ...
            C2(:,2); ...
            C3(:,2); ...
            C4(:,2)];

        xminFig = min(allX);
        xmaxFig = max(allX);

        yminFig = min(allY);
        ymaxFig = max(allY);

        cx = (xminFig + xmaxFig)/2;
        cy = (yminFig + ymaxFig)/2;

        rangeX = xmaxFig - xminFig;
        rangeY = ymaxFig - yminFig;

        halfRange = max(rangeX, rangeY)/2;

        minHalfRange = 4;
        marginFactor = 1.45;

        halfView = max( ...
            minHalfRange, ...
            halfRange * marginFactor);

        xlim(ax2, [ ...
            cx - halfView, ...
            cx + halfView]);

        ylim(ax2, [ ...
            cy - halfView, ...
            cy + halfView]);

    end

    drawnow limitrate;

    % ==========================================================
    % Store visual substrate variables
    % ==========================================================
    app.x = x;
    app.y = y;
    app.z = z;

    app.x2 = x2;
    app.y2 = y2;
    app.z2 = z2;

    app.x3 = x3;
    app.y3 = y3;
    app.z3 = z3;

    app.x4 = x4;
    app.y4 = y4;
    app.z4 = z4;

    % ==========================================================
    % Store contour variables for compatibility
    % ==========================================================
    app.P1 = C1(:,1).';
    app.P2 = C1(:,2).';

    app.xp3 = C2(:,1).';
    app.yp4 = C2(:,2).';

    app.xpp3 = C3(:,1).';
    app.ypp4 = C3(:,2).';

    app.xppp3 = C4(:,1).';
    app.yppp4 = C4(:,2).';

    app.PP1 = C1(:,1).';
    app.PP2 = C1(:,2).';

    app.Pxp3 = C2(:,1).';
    app.Pyp4 = C2(:,2).';

    app.PP3 = C3(:,1).';
    app.PP4 = C3(:,2).';

    app.Pxppp3 = C4(:,1).';
    app.Pyppp4 = C4(:,2).';

    app.W = W;
    app.L = L;

end

function modo = obtenerModoActual(app)

    if app.AbsorberButton.Value
        modo = "Absorber";
    elseif app.TransmitterButton.Value
        modo = "Transmitter";
    elseif app.SensorButton.Value
        modo = "Sensor";
    else
        modo = "Absorber";
    end

end

function RotacionFiguraSliderValueChanging(app, event)

    app.anguloRotacionVista = event.Value;

    if isempty(app.contornosConcentricosBase) || numel(app.contornosConcentricosBase) < 4
        return;
    end

    app.actualizarContornosDesdeControles();
    app.redrawConcentricContours3D();
    app.invalidarModeloCST();

end    

function setEnglishUILabels(app)

    % ==========================================================
    % Main window
    % ==========================================================
    app.UIFigure.Name = 'GielisMeta-CST';
    app.UIFigure.Position = [100 80 1250 760];
    % ==========================================================
    % Panels
    % ==========================================================
    app.GielisMetaCSTPanel.Title = 'GielisMeta-CST';

    app.SubstrateandmetalPanel.Title = 'Substrate and metal';
    app.GielisgeometryPanel.Title = 'Gielis geometry';
    app.CSTmaterialsPanel.Title = 'CST materials';
    app.GieliscurvepreviewPanel.Title = 'Gielis curve preview';
    app.ModeconfigurationPanel.Title = 'Mode configuration';
    app.SimulationchecklistPanel.Title = 'Simulation checklist';
    app.ConcentricstructurePanel.Title = 'Concentric structure';

    % Generic panel, if used for structure configuration
    app.Panel.Title = 'Structure configuration';

    % ==========================================================
    % Substrate and metal labels
    % ==========================================================
    app.LsubstratemmSpinnerLabel.Text = 'L substrate (mm)';
    app.WsubstratemmSpinnerLabel.Text = 'W substrate (mm)';
    app.hsubstratemmSpinnerLabel.Text = 'h substrate (mm)';
    app.h1coppermmSpinnerLabel.Text = 'Copper t. (mm)';
    app.gmmSpinnerLabel.Text = 'Gap (mm)';

    % ==========================================================
    % Gielis parameter labels
    % ==========================================================
    app.aSpinnerLabel.Text = 'a';
    app.bSpinnerLabel.Text = 'b';
    app.mSpinnerLabel.Text = 'm';
    app.n1SpinnerLabel.Text = 'n1';
    app.n2SpinnerLabel.Text = 'n2';
    app.n3SpinnerLabel.Text = 'n3';

    % ==========================================================
    % Materials
    % ==========================================================
    app.ConductorDropDownLabel.Text = 'Conductor';
    app.DielectricDropDownLabel.Text = 'Dielectric';
    app.dielectrictanSpinnerLabel.Text = 'Dielectric tanδ';
    app.ErSpinnerLabel.Text = 'Er';

    app.ConductorDropDown.Items = {'Copper (annealed)', 'PEC'};
    app.ConductorDropDown.Value = 'Copper (annealed)';

    app.DielectricDropDown.Items = { ...
        'FR4_lossy', ...
        'Rogers_RO4003C', ...
        'Rogers_RO5880', ...
        'Alumina', ...
        'Custom_Er'};

    if ~any(strcmp(app.DielectricDropDown.Value, app.DielectricDropDown.Items))
        app.DielectricDropDown.Value = 'FR4_lossy';
    end

    % ==========================================================
    % Structure configuration
    % ==========================================================
    app.ButtonGroup_2.Title = '';
    app.NormalButton.Text = 'Normal';
    app.ComplementaryButton.Text = 'Complementary';

    % ==========================================================
    % Mode configuration
    % ==========================================================
    app.ButtonGroup_3.Title = '';
    app.AbsorberButton.Text = 'Absorber';
    app.TransmitterButton.Text = 'Transmitter';
    app.SensorButton.Text = 'Sensor';

    app.RangodefrecuenciaLabel.Text = 'Frequency range';

    app.RangodefrecuenciaSpinnerLabel_2.Text = 'Ports';
    app.RangodefrecuenciaSpinnerLabel_3.Text = 'Boundaries';
    app.RangodefrecuenciaSpinnerLabel_4.Text = 'Result';


    app.DropDown.Items = {'GHz', 'MHz'};
    if ~any(strcmp(app.DropDown.Value, app.DropDown.Items))
        app.DropDown.Value = 'GHz';
    end

    % ==========================================================
    % Electromagnetic configuration indicators
    % ==========================================================
    app.DropDown_2.Items = {
        'Normal incidence - Floquet', ...
        '2 waveguide ports'};

    app.DropDown_3.Items = {
        'Unit cell X/Y - Open Z', ...
        'Open all boundaries'};

    app.DropDown_4.Items = { ...
        'S-parameters', ...
        'Absorption'};

    % ==========================================================
    % Checklist
    % ==========================================================
    app.GeometrygeneratedCheckBox.Text = 'Geometry generated';
    app.MaterialsassignedCheckBox.Text = 'Materials assigned';
    app.PortsdefinedCheckBox.Text = 'Ports defined';
    app.BoundariesconfiguredCheckBox.Text = 'Boundaries configured';
    app.ResultdefinedCheckBox.Text = 'Result defined';

    % ==========================================================
    % Concentric structure controls
    % ==========================================================
    app.dxmmSpinnerLabel.Text = 'dx (mm)';
    app.dymmSpinnerLabel.Text = 'dy (mm)';
    app.LinewidthmmSpinnerLabel.Text = 'Line width (mm)';

    app.RotationSliderLabel.Text = 'Rotation';
    app.Size1SpinnerLabel.Text = 'Size 1';
app.Size2SpinnerLabel.Text = 'Size 2';

    % ==========================================================
    % Buttons
    % ==========================================================
    app.PreviewButton.Text = 'Preview';
    app.GeneratestructureButton.Text = 'Generate';
    app.BuildmodelinCSTButton.Text = 'Build model in CST';
    app.SimulateandpostprocessButton.Text = 'Simulate';

% ==========================================================
% Final layout correction
% ==========================================================

% Main window and main panel
app.UIFigure.Position = [100 0 1100 800];

app.GielisMetaCSTPanel.Position = [1 1 1080 800];
app.GielisMetaCSTPanel.Title = 'GielisMeta-CST';
app.GielisMetaCSTPanel.FontSize = 22;
app.GielisMetaCSTPanel.FontWeight = 'bold';
app.GielisMetaCSTPanel.ForegroundColor = [0 0 1];

% ==========================================================
% Top application mode selector
% ==========================================================
app.ButtonGroup_3.Title = '';
app.ButtonGroup_3.Position = [250 728 430 35];

app.AbsorberButton.Position = [55 7 95 22];
app.TransmitterButton.Position = [165 7 115 22];
app.SensorButton.Position = [300 7 90 22];

app.AbsorberButton.Text = 'Absorber';
app.TransmitterButton.Text = 'Transmitter';
app.SensorButton.Text = 'Sensor';

% ==========================================================
% Structure configuration panel
% ==========================================================
app.Panel.Title = 'Structure configuration';
%app.Panel.Position = [10 0 120 225];

% Hide duplicated old label
app.DielctricoDropDownLabel_2.Text = '';
app.DielctricoDropDownLabel_2.Visible = 'off';

% Structure type group
app.ButtonGroup_2.Title = '';
%app.ButtonGroup_2.Position = [12 82 185 58];

%app.NormalButton.Position = [10 30 95 22];
%app.ComplementaryButton.Position = [10 8 140 22];

app.NormalButton.Text = 'Normal';
app.ComplementaryButton.Text = 'Complementary';

% ==========================================================
% Right-side buttons
% ==========================================================
app.PreviewButton.Position(3) = 330;
app.GeneratestructureButton.Position(3) = 330;
app.BuildmodelinCSTButton.Position(3) = 330;
app.SimulateandpostprocessButton.Position(3) = 330;

app.PreviewButton.Text = 'Preview';
app.GeneratestructureButton.Text = 'Generate';
app.SimulateandpostprocessButton.Text = 'Simulate';


end
function actualizarInterfazPorModo(app)

    modo = app.obtenerModoActual();

    % ==========================================================
    % 1. Configuración común para todos los modos
    % ==========================================================

    % Los desplegables de configuración electromagnética se usan
    % como indicadores automáticos. El usuario no debe modificarlos
    % directamente porque cada modo define su propia configuración.
    app.DropDown_2.Enable = 'off';   % Ports
    app.DropDown_3.Enable = 'off';   % Boundaries
    app.DropDown_4.Enable = 'off';   % Result

    % Checklist siempre en modo solo lectura
    app.GeometrygeneratedCheckBox.Enable = 'off';
    app.MaterialsassignedCheckBox.Enable = 'off';
    app.PortsdefinedCheckBox.Enable = 'off';
    app.BoundariesconfiguredCheckBox.Enable = 'off';
    app.ResultdefinedCheckBox.Enable = 'off';

    % Por defecto, ocultar controles propios del sensor
    app.LinewidthmmSpinner.Enable = 'off';
    app.LinewidthmmSpinner.Visible = 'off';
    app.LinewidthmmSpinnerLabel.Visible = 'off';

    % Por defecto, permitir selección de estructura
    app.NormalButton.Enable = 'on';
    app.ComplementaryButton.Enable = 'on';

    % ==========================================================
    % 2. Configuración específica por modo
    % ==========================================================

switch modo

    case "Absorber"

        % Electromagnetic configuration
        app.DropDown_2.Value = 'Normal incidence - Floquet';
        app.DropDown_3.Value = 'Unit cell X/Y - Open Z';
        app.DropDown_4.Value = 'Absorption';

        % Structure type
        app.NormalButton.Enable = 'on';
        app.ComplementaryButton.Enable = 'on';

        % Sensor controls are not required
        app.LinewidthmmSpinner.Enable = 'off';
        app.LinewidthmmSpinner.Visible = 'off';
        app.LinewidthmmSpinnerLabel.Visible = 'off';

        % Keep the absorber centered
        app.dxmmSpinner.Value = 0;
        app.dymmSpinner.Value = 0;

        app.dxmmSpinner.Enable = 'off';
        app.dymmSpinner.Enable = 'off';
        app.dxmmSpinnerLabel.Enable = 'off';
        app.dymmSpinnerLabel.Enable = 'off';

        app.BuildmodelinCSTButton.Text = 'Build absorber';


    case "Transmitter"

        % Electromagnetic configuration
        app.DropDown_2.Value = 'Normal incidence - Floquet';
        app.DropDown_3.Value = 'Unit cell X/Y - Open Z';
        app.DropDown_4.Value = 'S-parameters';

        % Conventional structure by default
        app.NormalButton.Value = true;
        app.NormalButton.Enable = 'on';
        app.ComplementaryButton.Enable = 'on';

        % Sensor controls are not required
        app.LinewidthmmSpinner.Enable = 'off';
        app.LinewidthmmSpinner.Visible = 'off';
        app.LinewidthmmSpinnerLabel.Visible = 'off';

        % Keep the structure centered
        app.dxmmSpinner.Value = 0;
        app.dymmSpinner.Value = 0;

        app.dxmmSpinner.Enable = 'off';
        app.dymmSpinner.Enable = 'off';
        app.dxmmSpinnerLabel.Enable = 'off';
        app.dymmSpinnerLabel.Enable = 'off';

        app.BuildmodelinCSTButton.Text = 'Build transmitter';


    case "Sensor"

        % Electromagnetic configuration
        app.DropDown_2.Value = '2 waveguide ports';
        app.DropDown_3.Value = 'Open all boundaries';
        app.DropDown_4.Value = 'S-parameters';

        % Sensor must use the complementary structure
        app.ComplementaryButton.Value = true;
        app.ComplementaryButton.Enable = 'off';
        app.NormalButton.Enable = 'off';

        % Show microstrip-line control
        app.LinewidthmmSpinner.Enable = 'on';
        app.LinewidthmmSpinner.Visible = 'on';
        app.LinewidthmmSpinnerLabel.Visible = 'on';

        if isempty(app.LinewidthmmSpinner.Value) || ...
                app.LinewidthmmSpinner.Value <= 0

            app.LinewidthmmSpinner.Value = 3.0;

        end

        % Allow resonator displacement
        app.dxmmSpinner.Enable = 'on';
        app.dymmSpinner.Enable = 'on';
        app.dxmmSpinnerLabel.Enable = 'on';
        app.dymmSpinnerLabel.Enable = 'on';

        app.BuildmodelinCSTButton.Text = 'Build sensor';

end

    % ==========================================================
    % 3. Reiniciar estado CST al cambiar de modo
    % ==========================================================

    app.reiniciarChecklistCompleto();

    % ==========================================================
    % 4. Si ya existe geometría, actualizar visualización
    % ==========================================================

    if ~isempty(app.contornosConcentricosBase) && numel(app.contornosConcentricosBase) >= 4

        app.actualizarContornosDesdeControles();
        app.redrawConcentricContours3D();

        app.setChecklist(true, false, false, false, false, false);

    elseif ~isempty(app.contornosConcentricos) && numel(app.contornosConcentricos) >= 4

        app.redrawConcentricContours3D();

        app.setChecklist(true, false, false, false, false, false);

    else

        app.setChecklist(false, false, false, false, false, false);

    end

end

function reiniciarChecklistCompleto(app)

    hayGeo = app.hayGeometriaCSTLista();

    app.setChecklist( ...
        hayGeo, ...   % geometría
        false, ...    % materiales
        false, ...    % puertos
        false, ...    % fronteras
        false, ...    % monitor / resultado
        false);       % modelo CST listo

    app.CSTActual = [];
    app.rutaCSTActual = "";
    app.nombreProyectoCST = "";


end


function configurarCSTBasicoSegunModo(app, CST, modoAplicacion)

    % ==========================================================
    % 1. Read frequency range from the interface
    % ==========================================================
    fmin = app.FrequencyrangeSpinner.Value;
    fmax = app.RangodefrecuenciaSpinner_2.Value;
    unit = string(app.DropDown.Value);

    if fmin <= 0 || fmax <= 0 || fmin >= fmax

        uialert(app.UIFigure, ...
            "The frequency range is not valid. fmin must be lower than fmax.", ...
            "Frequency error");

        error("Invalid frequency range");

    end

    % Normalize unit text
    if strcmpi(unit, "Mhz")
        unit = "MHz";
    elseif strcmpi(unit, "Ghz")
        unit = "GHz";
    end

    % ==========================================================
    % 2. Define units, solver, and frequency range
    % ==========================================================
    CST.defineUnits('Geometry', 'mm', 'Frequency', char(unit));
    CST.setSolver('frequency');
    CST.setFreq(fmin, fmax);

    % ==========================================================
    % 3. Configure boundary conditions according to mode
    % ==========================================================
    switch string(modoAplicacion)

        case "Absorber"

            % Periodic absorber:
            % X/Y are configured as unit-cell boundaries.
            % Z is open for wave incidence and propagation.
            CST.setBoundaryCondition( ...
                'Xmin', 'Unit cell', ...
                'Xmax', 'Unit cell', ...
                'Ymin', 'Unit cell', ...
                'Ymax', 'Unit cell', ...
                'Zmin', 'Open add space', ...
                'Zmax', 'Open add space');


           case "Transmitter"

            % ==================================================
            % Transmission structure:
            % Unit-cell boundaries in X/Y and open space in Z.
            % This is more appropriate for a metasurface/unit-cell
            % transmission analysis.
            % ==================================================

            CST.setBoundaryCondition( ...
                'Xmin', 'Unit cell', ...
                'Xmax', 'Unit cell', ...
                'Ymin', 'Unit cell', ...
                'Ymax', 'Unit cell', ...
                'Zmin', 'Open add space', ...
                'Zmax', 'Open add space');


        case "Sensor"

            % Microstrip sensor:
            % Open domain with ports at both ends of the line.
            CST.setBoundaryCondition( ...
                'Xmin', 'Open add space', ...
                'Xmax', 'Open add space', ...
                'Ymin', 'Open add space', ...
                'Ymax', 'Open add space', ...
                'Zmin', 'Open add space', ...
                'Zmax', 'Open add space');


        otherwise

            uialert(app.UIFigure, ...
                "Application mode not recognized.", ...
                "Mode error");

            error("Unrecognized application mode");

    end

    % ==========================================================
    % 4. Configure result type according to the interface
    % ==========================================================
    selectedResult = string(app.DropDown_4.Value);

    switch selectedResult

        case "Absorption"

            % Absorption will be calculated later from S-parameters.
            % Field monitors are not created at this stage.
            app.ResultdefinedCheckBox.Value = true;

        case "S-parameters"

            % S-parameters are obtained from the ports or excitation.
            % No field monitor is required at this stage.
            app.ResultdefinedCheckBox.Value = true;

        otherwise

            % Safety fallback in case an old dropdown value remains.
            app.DropDown_4.Value = 'S-parameters';
            app.ResultdefinedCheckBox.Value = true;

    end

    % ==========================================================
    % 5. Update checklist
    % ==========================================================
    app.BoundariesconfiguredCheckBox.Value = true;

end

function configurarPuertosSegunModo(app, CST, modoAplicacion, W, L, Z, z1)

    switch string(modoAplicacion)

case "Absorber"

    CST.defineFloquetModes(2);

    app.DropDown_2.Value = 'Normal incidence - Floquet';
    app.PortsdefinedCheckBox.Value = true;

case "Transmitter"

    CST.defineFloquetModes(2);

    app.DropDown_2.Value = 'Normal incidence - Floquet';
    app.PortsdefinedCheckBox.Value = true;

    disp("Transmitter Floquet modes configured.");
    disp("Transmission is evaluated between Zmin and Zmax.");

             case "Sensor"

            % ==================================================
            % Sensor:
            % The microstrip line is oriented along X.
            % Two waveguide ports are placed at xmin and xmax.
            % ==================================================

            lineWidth = app.obtenerAnchoLineaSensor();

            if isempty(lineWidth) || ~isfinite(lineWidth) || lineWidth <= 0
                lineWidth = 3.0;
            end

            % ==================================================
            % Port window in Y direction
            % ==================================================
            % The port must include the microstrip width and enough
            % fringing field around it.

            portMarginY = max(2*Z, lineWidth);

            yPort = [
                -lineWidth/2 - portMarginY, ...
                 lineWidth/2 + portMarginY
            ];

            yLimit = L/2 - 0.10;

            if yLimit <= 0

                uialert(app.UIFigure, ...
                    "The substrate length L is too small for sensor ports.", ...
                    "Invalid sensor port");

                error("Invalid sensor port: substrate L is too small.");

            end

            yPort(1) = max(yPort(1), -yLimit);
            yPort(2) = min(yPort(2),  yLimit);

            if yPort(2) <= yPort(1)

                uialert(app.UIFigure, ...
                    "The sensor port width is invalid. Increase L substrate or reduce line width.", ...
                    "Invalid sensor port");

                error("Invalid sensor waveguide port width.");

            end

            % ==================================================
            % Port window in Z direction
            % ==================================================
            % The port must include:
            % - bottom ground plane
            % - dielectric substrate
            % - upper microstrip line
            % - air above the line

            zBottom = -Z - z1 - 0.50*Z;
            zTop    =  z1 + 1.50*Z;

            zPort = [zBottom, zTop];

            if zPort(2) <= zPort(1)

                uialert(app.UIFigure, ...
                    "The sensor port height is invalid.", ...
                    "Invalid sensor port");

                error("Invalid sensor waveguide port height.");

            end

            % ==================================================
            % Create input and output waveguide ports
            % ==================================================
            disp("Creating sensor waveguide port 1 at xmin...");
            CST.addWaveguidePort( ...
                'xmin', ...
                -W/2, ...
                yPort, ...
                zPort);

            disp("Creating sensor waveguide port 2 at xmax...");
            CST.addWaveguidePort( ...
                'xmax', ...
                 W/2, ...
                yPort, ...
                zPort);

            app.DropDown_2.Value = '2 waveguide ports';
            app.PortsdefinedCheckBox.Value = true;
            app.PortsdefinedCheckBox.Value = true;

            disp("Sensor waveguide ports created successfully.");
            disp("Port 1 plane: xmin, x = " + string(-W/2) + " mm");
            disp("Port 2 plane: xmax, x = " + string( W/2) + " mm");
            disp("yPort = [" + string(yPort(1)) + ", " + string(yPort(2)) + "] mm");
            disp("zPort = [" + string(zPort(1)) + ", " + string(zPort(2)) + "] mm");
        otherwise

            uialert(app.UIFigure, ...
                "The application mode was not recognized while configuring ports.", ...
                "Port configuration error");

            error("Unrecognized mode for port configuration");

    end

end

function tipoSeleccionado = seleccionarSParameterPostproceso(app, CST, tituloVentana)

    try
        [tipos, ~] = CST.getSParamStrings();
        tipos = string(tipos);
    catch ME
        uialert(app.UIFigure, ...
            "Could not read the S-parameter list." + newline + ...
            ME.message, ...
            "Postprocessing error");
        tipoSeleccionado = "";
        return;
    end

    if isempty(tipos)
        uialert(app.UIFigure, ...
            "No S-parameters are available." + newline + ...
            "This usually means that CST does not have a valid excitation." + newline + ...
            "Check that the model has ports, Floquet modes, or waveguide ports before running the simulation.", ...
            "No S-parameters");
        tipoSeleccionado = "";
        return;
    end

    [idx, ok] = listdlg( ...
        'PromptString', 'Select the S-parameter for postprocessing:', ...
        'SelectionMode', 'single', ...
        'ListString', cellstr(tipos), ...
        'Name', char(tituloVentana), ...
        'ListSize', [360 300]);

    if ok == 0
        tipoSeleccionado = "";
        return;
    end

    tipoSeleccionado = tipos(idx);

end

function calcularAbsorcionFisicaZmaxModo1(app, CST)

    try
        [tipos, ~] = CST.getSParamStrings();
        tipos = string(tipos);
        tipos = strtrim(tipos);

    catch ME

        uialert(app.UIFigure, ...
            "Could not read the S-parameter list." + newline + ME.message, ...
            "Postprocessing error");
        return;

    end

    if isempty(tipos)

        uialert(app.UIFigure, ...
            "No S-parameters are available. Run the simulation first.", ...
            "No results");
        return;

    end

    % ==========================================================
    % Reflection from Zmax incidence, mode 1
    % ==========================================================
    candidatosCo = [
        "SZmax(1),Zmax(1)"
        "SZmax,Zmax"
        "S1,1"
        "S11"
    ];

    candidatosCross = [
        "SZmax(2),Zmax(1)"
    ];

    % ==========================================================
    % Transmission from Zmax incidence, mode 1
    % ==========================================================
    candidatosTco = [
        "SZmin(1),Zmax(1)"
        "SZmin,Zmax"
        "S2,1"
        "S21"
    ];

    candidatosTcross = [
        "SZmin(2),Zmax(1)"
    ];

    % ==========================================================
    % Read main co-polar reflection term
    % ==========================================================
    [freq, ScoLin, tipoCo] = leerS(candidatosCo);

    if tipoCo == ""

        tipoManual = app.seleccionarSParameterPostproceso( ...
            CST, ...
            'Select the main reflection term for the absorber');

        if tipoManual == ""
            return;
        end

        try
            [freq, Sparam, ~] = CST.getSParameters(char(tipoManual));
        catch
            [freq, Sparam, ~] = CST.getSParams(char(tipoManual));
        end

        Sparam = squeeze(Sparam);
        ScoLin = app.convertirSparamAMagnitudLineal(Sparam);
        tipoCo = tipoManual;

    end

    % ==========================================================
    % Read optional cross-polar and transmission terms
    % ==========================================================
    [freqCross, ScrossLin, tipoCross] = leerS(candidatosCross);
    [freqTco, TcoLin, tipoTco] = leerS(candidatosTco);
    [freqTcross, TcrossLin, tipoTcross] = leerS(candidatosTcross);

    ScrossLin = ajustarFrecuencia(freqCross, ScrossLin, freq);
    TcoLin = ajustarFrecuencia(freqTco, TcoLin, freq);
    TcrossLin = ajustarFrecuencia(freqTcross, TcrossLin, freq);

    % ==========================================================
    % Power balance
    % ==========================================================
    Rtotal = ScoLin(:).^2 + ScrossLin(:).^2;

% Para absorbedor con plano de tierra, la transmisión física se considera nula
    Ttotal = zeros(size(Rtotal));

    A = 1 - Rtotal;

    Aplot = real(A);

    Aplot(Aplot < 0) = 0;
    Aplot(Aplot > 1) = 1;

    % ==========================================================
    % Plot absorption, reflection, and transmission
    % ==========================================================
    figure;
    plot(freq, Aplot, 'LineWidth', 2.0);
    hold on;
    plot(freq, Rtotal, '--', 'LineWidth', 1.4);
    plot(freq, Ttotal, ':', 'LineWidth', 1.8);
    hold off;

    grid on;
    xlabel('Frequency');
    ylabel('Normalized power');
    title('Useful absorption - Zmax incidence mode 1');

    legend( ...
    'Absorption A = 1 - R', ...
    'Total reflection R', ...
    'Transmission T \approx 0', ...
    'Location', 'best');

    ylim([0 1.05]);

    % ==========================================================
    % Summary
    % ==========================================================
    [Amax, idxMax] = max(Aplot);
    fmaxAbs = freq(idxMax);

    if tipoCross == ""
        tipoCrossTxt = "Not available";
    else
        tipoCrossTxt = string(tipoCross);
    end

    if tipoTco == ""
        tipoTcoTxt = "Not available";
    else
        tipoTcoTxt = string(tipoTco);
    end

    if tipoTcross == ""
        tipoTcrossTxt = "Not available";
    else
        tipoTcrossTxt = string(tipoTcross);
    end

    mensaje = ...
        "Co-polar reflection S: " + string(tipoCo) + newline + ...
        "Cross-polar reflection S: " + tipoCrossTxt + newline + ...
        "Co-polar transmission S: " + tipoTcoTxt + newline + ...
        "Cross-polar transmission S: " + tipoTcrossTxt + newline + newline + ...
        "Maximum absorption = " + string(Amax) + newline + ...
        "Frequency = " + string(fmaxAbs) + newline + newline + ...
        "Rtotal at maximum = " + string(Rtotal(idxMax)) + newline + ...
        "Ttotal at maximum = " + string(Ttotal(idxMax));

    if max(Ttotal) > 0.05

        mensaje = mensaje + newline + newline + ...
            "Warning: significant transmission was detected." + newline + ...
            "For an absorber with a ground plane, Ttotal should be close to zero.";

    end

    uialert(app.UIFigure, ...
        mensaje, ...
        "Absorption calculated");

    % ==========================================================
    % Internal function: read one available S-parameter
    % ==========================================================
    function [freqOut, SlinOut, tipoElegido] = leerS(candidatos)

        freqOut = [];
        SlinOut = [];
        tipoElegido = "";

        for kk = 1:numel(candidatos)

            idx = strcmpi(tipos, candidatos(kk));

            if any(idx)
                tipoElegido = tipos(find(idx, 1, 'first'));
                break;
            end

        end

        if tipoElegido == ""
            return;
        end

        try
            [freqOut, Sparam, ~] = CST.getSParameters(char(tipoElegido));
        catch
            [freqOut, Sparam, ~] = CST.getSParams(char(tipoElegido));
        end

        Sparam = squeeze(Sparam);
        SlinOut = app.convertirSparamAMagnitudLineal(Sparam);

        freqOut = freqOut(:);
        SlinOut = SlinOut(:);

    end

    % ==========================================================
    % Internal function: match frequency vectors
    % ==========================================================
    function Sfinal = ajustarFrecuencia(freqDato, Sdato, freqBase)

        if isempty(Sdato)
            Sfinal = zeros(size(freqBase(:)));
            return;
        end

        freqDato = freqDato(:);
        Sdato = Sdato(:);
        freqBase = freqBase(:);

        if numel(freqDato) == numel(freqBase) && max(abs(freqDato - freqBase)) < 1e-9
            Sfinal = Sdato;
        else
            Sfinal = interp1(freqDato, Sdato, freqBase, 'linear', 'extrap');
        end

    end

end

function Slin = convertirSparamAMagnitudLineal(app, S)

    S = squeeze(S);

    if isempty(S)
        Slin = [];
        return;
    end

    if ~isreal(S)
        Slin = abs(S);
        return;
    end

    % Si tiene valores negativos, probablemente está en dB.
    % Una magnitud lineal real no debería ser negativa.
    if any(S < 0) || max(abs(S)) > 1.2
        Slin = 10.^(S/20);
    else
        Slin = abs(S);
    end

end

function postprocesarResultadosSegunModo(app, CST, modoAplicacion)

    switch string(modoAplicacion)

        case "Absorber"

            % ======================================================
            % Absorber:
            % Physical absorption is calculated from reflection and
            % possible transmission terms.
            % ======================================================
            app.calcularAbsorcionFisicaZmaxModo1(CST);

        case "Transmitter"

            % ======================================================
            % Transmitter:
            % Main result is S21 in dB.
            % ======================================================
            [freq, S21lin, tipoS21] = app.leerS21Principal(CST);

            if isempty(freq) || isempty(S21lin)
                return;
            end

            S21lin = abs(S21lin);
            S21lin(S21lin <= 0) = eps;

            S21dB = 20*log10(S21lin);

            figure;
            plot(freq, S21dB, 'LineWidth', 1.8);
            grid on;

            xlabel('Frequency');
            ylabel('|S21| (dB)');
            title("Main transmission: " + string(tipoS21));

            [Smin, idxMin] = min(S21dB);
            [Smax, idxMax] = max(S21dB);

            fMin = freq(idxMin);
            fMax = freq(idxMax);

            resumenCampo = ...
                "S21 used: " + string(tipoS21) + newline + ...
                "Minimum = " + string(Smin) + " dB at f = " + string(fMin) + newline + ...
                "Maximum = " + string(Smax) + " dB at f = " + string(fMax);

            [fCampo, tipoCampo, okCampo] = app.pedirMonitorCampoPostSim(fMin, resumenCampo);

            if okCampo
                app.agregarMonitoresCampoYReSimular(CST, fCampo, tipoCampo);
            end

        case "Sensor"

            % ======================================================
            % Sensor:
            % Main result is the resonance dip in S21.
            % ======================================================
            [freq, S21lin, tipoS21] = app.leerS21Principal(CST);

            if isempty(freq) || isempty(S21lin)
                return;
            end

            S21lin = abs(S21lin);
            S21lin(S21lin <= 0) = eps;

            S21dB = 20*log10(S21lin);

            figure;
            plot(freq, S21dB, 'LineWidth', 1.8);
            grid on;

            xlabel('Frequency');
            ylabel('|S21| (dB)');
            title("Sensor response: " + string(tipoS21));

            [Smin, idxMin] = min(S21dB);
            fr = freq(idxMin);

            resumenCampo = ...
                "S21 used: " + string(tipoS21) + newline + ...
                "Estimated resonant frequency = " + string(fr) + newline + ...
                "Minimum = " + string(Smin) + " dB";

            [fCampo, tipoCampo, okCampo] = app.pedirMonitorCampoPostSim(fr, resumenCampo);

            if okCampo
                app.agregarMonitoresCampoYReSimular(CST, fCampo, tipoCampo);
            end

        otherwise

            uialert(app.UIFigure, ...
                "The selected application mode is not recognized for postprocessing.", ...
                "Postprocessing mode error");

    end

end

function [matCond, matDiel] = configurarMaterialesCST(app, CST)

    % ==========================================================
    % 1. Conductor material
    % ==========================================================
    opcionCond = lower(strtrim(string(app.ConductorDropDown.Value)));

    switch opcionCond

        case {"cobre", "copper", "copper (annealed)"}
            matCond = 'Copper (annealed)';

        case "pec"
            matCond = 'PEC';

        otherwise
            matCond = 'Copper (annealed)';

    end

    % ==========================================================
    % 2. Dielectric material
    % ==========================================================
    opcionDiel = lower(strtrim(string(app.DielectricDropDown.Value)));

    switch opcionDiel

        case "fr4_lossy"

            Er = 4.3;
            tand = 0.02;
            matDiel = 'FR4_lossy_App';

        case "rogers_ro4003c"

            Er = 3.55;
            tand = 0.0027;
            matDiel = 'Rogers_RO4003C_App';

        case "rogers_ro5880"

            Er = 2.2;
            tand = 0.0009;
            matDiel = 'Rogers_RO5880_App';

        case "alumina"

            Er = 9.8;
            tand = 0.0001;
            matDiel = 'Alumina_App';

        case {"custom_er", "personalizado_er"}

            Er = app.ErSpinner.Value;
            tand = app.dielectrictanSpinner.Value;

            if isempty(Er) || Er <= 0
                Er = 4.3;
            end

            if isempty(tand) || tand < 0
                tand = 0.02;
            end

            matDiel = char(matlab.lang.makeValidName( ...
                sprintf('Substrate_Er_%0.3f_tand_%0.4f', Er, tand)));

        otherwise

            Er = app.ErSpinner.Value;
            tand = app.dielectrictanSpinner.Value;

            if isempty(Er) || Er <= 0
                Er = 4.3;
            end

            if isempty(tand) || tand < 0
                tand = 0.02;
            end

            matDiel = 'Substrate_App';

    end

    % ==========================================================
    % 3. Update interface values
    % ==========================================================
    app.ErSpinner.Value = Er;
    app.dielectrictanSpinner.Value = tand;

    % ==========================================================
    % 4. Create dielectric material in CST
    % ==========================================================
    color_beige = [0.96, 0.86, 0.75];

    try

        CST.addNormalMaterial( ...
            matDiel, ...
            Er, ...
            1, ...
            color_beige, ...
            'tand', tand);

    catch ME

        disp("Warning while creating dielectric material:");
        disp(ME.message);

    end

    % ==========================================================
    % 5. Update checklist
    % ==========================================================
    app.MaterialsassignedCheckBox.Value = true;

end

function explorarAbsorcionSParameters(app, CST)

    try
        [tipos, ~] = CST.getSParamStrings();
        tipos = string(tipos);
    catch ME
        uialert(app.UIFigure, ...
            "Could not read the S-parameter list." + newline + ...
            ME.message, ...
            "Postprocessing error");
        return;
    end

    if isempty(tipos)
        uialert(app.UIFigure, ...
            "No S-parameters are available. Run the simulation first.", ...
            "No results");
        return;
    end

    % ==========================================================
    % Create postprocessing window
    % ==========================================================
    fig = uifigure( ...
        'Name', 'Individual 1 - |S|^2 explorer', ...
        'Position', [200 120 950 600]);

    panelLista = uipanel(fig, ...
        'Title', 'Available S-parameters', ...
        'Position', [15 70 260 510]);

    % ESTA PARTE TE FALTABA
    listaS = uilistbox(panelLista, ...
        'Items', cellstr(tipos), ...
        'Position', [10 50 240 420]);

    infoLabel = uilabel(panelLista, ...
        'Position', [10 15 240 25], ...
        'Text', 'Select an S-parameter');

    % ESTA PARTE TAMBIÉN TE FALTABA
    ax = uiaxes(fig, ...
        'Position', [300 120 620 430]);

    title(ax, 'Individual exploration');
    xlabel(ax, 'Frequency');
    ylabel(ax, '1 - |S|^2');
    grid(ax, 'on');
    ylim(ax, [0 1]);

    resultadoLabel = uilabel(fig, ...
        'Position', [300 555 620 30], ...
        'FontWeight', 'bold', ...
        'Text', 'Individual 1 - |S|^2 explorer');

    advertenciaLabel = uilabel(fig, ...
        'Position', [300 525 620 25], ...
        'FontColor', [0.65 0.10 0.10], ...
        'Text', 'Note: this curve is exploratory. Physical absorption must be calculated using the reflected terms of the incident mode.');

    uibutton(fig, ...
        'push', ...
        'Text', 'Previous', ...
        'Position', [300 50 120 35], ...
        'ButtonPushedFcn', @(src,event) cambiarIndice(-1));

    uibutton(fig, ...
        'push', ...
        'Text', 'Next', ...
        'Position', [435 50 120 35], ...
        'ButtonPushedFcn', @(src,event) cambiarIndice(1));

    uibutton(fig, ...
        'push', ...
        'Text', 'Export current curve', ...
        'Position', [570 50 160 35], ...
        'ButtonPushedFcn', @(src,event) exportarCurvaActual());

    uibutton(fig, ...
        'push', ...
        'Text', 'Physical absorption Zmax', ...
        'Position', [745 50 170 35], ...
        'ButtonPushedFcn', @(src,event) app.calcularAbsorcionFisicaZmaxModo1(CST));

    datosActuales.freq = [];
    datosActuales.A = [];
    datosActuales.tipoS = "";

    listaS.ValueChangedFcn = @(src,event) graficarS(src.Value);

    % Plot first S-parameter automatically
    listaS.Value = listaS.Items{1};
    graficarS(listaS.Value);

    % ==========================================================
    % Internal function: plot 1 - |S|^2
    % ==========================================================
    function graficarS(tipoS)

        try
            [freq, Sparam, ~] = CST.getSParameters(char(tipoS));
        catch
            try
                [freq, Sparam, ~] = CST.getSParams(char(tipoS));
            catch ME
                uialert(fig, ...
                    "Could not read the selected S-parameter." + newline + ...
                    ME.message, ...
                    "S-parameter reading error");
                return;
            end
        end

        Sparam = squeeze(Sparam);

        if isempty(freq) || isempty(Sparam)
            uialert(fig, ...
                "The selected S-parameter does not contain valid data.", ...
                "No data");
            return;
        end

        Slin = app.convertirSparamAMagnitudLineal(Sparam);

        A = 1 - abs(Slin).^2;
        A = real(A);

        A(A < 0) = 0;
        A(A > 1) = 1;

        cla(ax);
        plot(ax, freq, A, 'LineWidth', 1.8);
        grid(ax, 'on');
        xlabel(ax, 'Frequency');
        ylabel(ax, '1 - |S|^2');
        title(ax, "1 - |" + string(tipoS) + "|^2");
        ylim(ax, [0 1]);

        [Amax, idxMax] = max(A);
        fMax = freq(idxMax);

        resultadoLabel.Text = ...
            "S used: " + string(tipoS) + ...
            "   |   Maximum = " + string(Amax) + ...
            "   |   Frequency = " + string(fMax);

        infoLabel.Text = "Current: " + string(tipoS);

        datosActuales.freq = freq;
        datosActuales.A = A;
        datosActuales.tipoS = string(tipoS);

    end

    % ==========================================================
    % Internal function: previous / next
    % ==========================================================
    function cambiarIndice(delta)

        items = string(listaS.Items);
        actual = string(listaS.Value);

        idx = find(items == actual, 1);

        if isempty(idx)
            idx = 1;
        end

        idxNuevo = idx + delta;

        if idxNuevo < 1
            idxNuevo = numel(items);
        elseif idxNuevo > numel(items)
            idxNuevo = 1;
        end

        listaS.Value = listaS.Items{idxNuevo};
        graficarS(listaS.Value);

    end

    % ==========================================================
    % Internal function: export current curve
    % ==========================================================
    function exportarCurvaActual()

        if isempty(datosActuales.freq) || isempty(datosActuales.A)
            uialert(fig, ...
                "There is no curve to export.", ...
                "No data");
            return;
        end

        nombreVariable = matlab.lang.makeValidName( ...
            "Absorption_" + replace(datosActuales.tipoS, ...
            ["(",")",",","-"," "], "_"));

        datos = table( ...
            datosActuales.freq(:), ...
            datosActuales.A(:), ...
            'VariableNames', {'Frequency', 'Absorption'});

        assignin('base', nombreVariable, datos);

        uialert(fig, ...
            "Curve exported to the Workspace as:" + newline + nombreVariable, ...
            "Export completed");

    end

end

function [freq, Slin, tipoElegido] = leerS21Principal(app, CST)

    % ==========================================================
    % Read the main S21 parameter according to the current mode
    % ==========================================================
    % Transmitter:
    %   ports are placed along Z, so SZ... is prioritized.
    %
    % Sensor:
    %   microstrip line is oriented along X, so SX... is prioritized.
    % ==========================================================

    freq = [];
    Slin = [];
    tipoElegido = "";

    modoActual = app.obtenerModoActual();

    % ==========================================================
    % 1. Select S21 candidates according to application mode
    % ==========================================================
    switch string(modoActual)

        case "Transmitter"

            % ==================================================
            % Transmitter uses ports at zmax and zmin
            % ==================================================
            candidatos = [
                "SZmin(1),Zmax(1)"
                "SZmin,Zmax"
                "SZmax(1),Zmin(1)"
                "SZmax,Zmin"
                "S2,1"
                "S21"
                "S1,2"
                "S12"
            ];

        case "Sensor"

            % ==================================================
            % Sensor uses ports at xmax and xmin
            % ==================================================
            candidatos = [
                "SXmax(1),Xmin(1)"
                "SXmax,Xmin"
                "SXmin(1),Xmax(1)"
                "SXmin,Xmax"
                "S2,1"
                "S21"
                "S1,2"
                "S12"
            ];

        otherwise

            % ==================================================
            % Generic fallback
            % ==================================================
            candidatos = [
                "S2,1"
                "S21"
                "S1,2"
                "S12"
                "SZmin(1),Zmax(1)"
                "SZmin,Zmax"
                "SZmax(1),Zmin(1)"
                "SZmax,Zmin"
                "SXmax(1),Xmin(1)"
                "SXmax,Xmin"
                "SXmin(1),Xmax(1)"
                "SXmin,Xmax"
                "SYmax(1),Ymin(1)"
                "SYmax,Ymin"
                "SYmin(1),Ymax(1)"
                "SYmin,Ymax"
            ];

    end

    % ==========================================================
    % 2. Read available S-parameter names from CST
    % ==========================================================
    try

        [tipos, ~] = CST.getSParamStrings();
        tipos = string(tipos);
        tipos = strtrim(tipos);

    catch ME

        uialert(app.UIFigure, ...
            "Could not read the S-parameter list." + newline + ...
            ME.message, ...
            "Postprocessing error");

        return;

    end

    if isempty(tipos)

        uialert(app.UIFigure, ...
            "No S-parameters are available. Run the simulation first.", ...
            "No results");

        return;

    end

    disp("Available S-parameters in CST:");
    disp(tipos);

    % ==========================================================
    % 3. Search exact candidate match
    % ==========================================================
    for k = 1:numel(candidatos)

        idx = strcmpi(tipos, candidatos(k));

        if any(idx)
            tipoElegido = tipos(find(idx, 1, 'first'));
            break;
        end

    end

    % ==========================================================
    % 4. If no exact match is found, search by contained text
    % ==========================================================
    if tipoElegido == ""

        for k = 1:numel(candidatos)

            idx = contains(tipos, candidatos(k), 'IgnoreCase', true);

            if any(idx)
                tipoElegido = tipos(find(idx, 1, 'first'));
                break;
            end

        end

    end

    % ==========================================================
    % 5. Manual fallback
    % ==========================================================
    if tipoElegido == ""

        tipoElegido = app.seleccionarSParameterPostproceso( ...
            CST, ...
            'Select the main S21 parameter');

        if tipoElegido == ""
            return;
        end

    end

    disp("Selected S21 parameter:");
    disp(tipoElegido);

    % ==========================================================
    % 6. Read selected S-parameter data
    % ==========================================================
    try

        [freq, Sparam, ~] = CST.getSParameters(char(tipoElegido));

    catch

        try

            [freq, Sparam, ~] = CST.getSParams(char(tipoElegido));

        catch ME

            uialert(app.UIFigure, ...
                "Could not read the selected S-parameter." + newline + ...
                ME.message, ...
                "S-parameter reading error");

            freq = [];
            Slin = [];

            return;

        end

    end

    % ==========================================================
    % 7. Convert to linear magnitude
    % ==========================================================
    Sparam = squeeze(Sparam);
    Slin = app.convertirSparamAMagnitudLineal(Sparam);

    freq = freq(:);
    Slin = Slin(:);

end

function [freq, Sparam, tipoElegido] = leerSParamDisponible(app, CST, patrones)

    % ==========================================================
    % Search for an available S-parameter in CST using text patterns.
    % This avoids depending only on simple names such as S11 or S21.
    % ==========================================================

    freq = [];
    Sparam = [];
    tipoElegido = "";

    try
        [tipos, ~] = CST.getSParamStrings();
        tipos = string(tipos);
        tipos = strtrim(tipos);
    catch ME
        uialert(app.UIFigure, ...
            "Could not read the S-parameter list." + newline + ...
            ME.message, ...
            "Postprocessing error");
        return;
    end

    if isempty(tipos)
        uialert(app.UIFigure, ...
            "No S-parameters are available. Run the simulation first.", ...
            "No results");
        return;
    end

    for k = 1:numel(patrones)

        patron = string(patrones{k});
        idx = contains(tipos, patron, 'IgnoreCase', true);

        if any(idx)
            tipoElegido = tipos(find(idx, 1, 'first'));
            break;
        end

    end

    if tipoElegido == ""

        disponibles = strjoin(tipos, newline);

        uialert(app.UIFigure, ...
            "The requested S-parameter was not found." + newline + newline + ...
            "Available results:" + newline + disponibles, ...
            "S-parameter not found");

        return;

    end

    try
        [freq, Sparam, ~] = CST.getSParameters(char(tipoElegido));
    catch
        try
            [freq, Sparam, ~] = CST.getSParams(char(tipoElegido));
        catch ME
            uialert(app.UIFigure, ...
                "Could not read the selected S-parameter." + newline + ...
                ME.message, ...
                "S-parameter reading error");
            freq = [];
            Sparam = [];
            tipoElegido = "";
            return;
        end
    end

    Sparam = squeeze(Sparam);

end

function matCond = obtenerMaterialConductorSimple(app)

    opcionCond = lower(strtrim(string(app.ConductorDropDown.Value)));

    switch opcionCond

        case {"cobre", "copper", "copper (annealed)"}

            matCond = 'Copper (annealed)';

        case {"pec", "perfect electric conductor"}

            matCond = 'PEC';

        otherwise

            matCond = 'Copper (annealed)';

    end

end


function anchoLinea = obtenerAnchoLineaSensor(app)

    anchoLinea = app.LinewidthmmSpinner.Value;

    if isempty(anchoLinea) || anchoLinea <= 0
        anchoLinea = 1.5;
    end

end

function construirSensorMicrostripGielis(app, CST, points1, points2, points3, points4, W, L, Z, z1)

    % ==========================================================
    % Sensor structure:
    % - uniform top microstrip line
    % - dielectric substrate
    % - bottom ground plane with closed Gielis-CSRR slots
    %
    % Stable rollback version:
    % - no split bridges
    % - no auxiliary methods
    % - no open-slot reconstruction
    % ==========================================================

    % ==========================================================
    % 1. Get conductor material
    % ==========================================================
    matCond = app.obtenerMaterialConductorSimple();

    % ==========================================================
    % 2. Validate Gielis-CSRR points
    % ==========================================================
    allPts = [
        points1(:,1:2);
        points2(:,1:2);
        points3(:,1:2);
        points4(:,1:2)
    ];

    validPts = isfinite(allPts(:,1)) & isfinite(allPts(:,2));
    allPts = allPts(validPts,:);

    if isempty(allPts)

        uialert(app.UIFigure, ...
            "The Gielis-CSRR points are empty or invalid.", ...
            "Invalid sensor resonator");

        error("Invalid Gielis-CSRR points.");

    end

    resonatorWidth  = max(allPts(:,1)) - min(allPts(:,1));
    resonatorHeight = max(allPts(:,2)) - min(allPts(:,2));

    % ==========================================================
    % 3. Validate resonator inside substrate
    % ==========================================================
    substrateMargin = 0.10; % mm

    xminRes = min(allPts(:,1));
    xmaxRes = max(allPts(:,1));
    yminRes = min(allPts(:,2));
    ymaxRes = max(allPts(:,2));

    if xminRes < -W/2 + substrateMargin || xmaxRes > W/2 - substrateMargin || ...
       yminRes < -L/2 + substrateMargin || ymaxRes > L/2 - substrateMargin

        uialert(app.UIFigure, ...
            "The Gielis-CSRR is outside the substrate." + newline + newline + ...
            "Current resonator limits:" + newline + ...
            "X = [" + string(xminRes) + ", " + string(xmaxRes) + "] mm" + newline + ...
            "Y = [" + string(yminRes) + ", " + string(ymaxRes) + "] mm" + newline + newline + ...
            "Increase W/L substrate or reduce Size 1 / Size 2.", ...
            "Gielis-CSRR outside substrate");

        error("Gielis-CSRR is outside the substrate.");

    end

    % ==========================================================
    % 4. Create full bottom ground plane
    % ==========================================================
    CST.addBrick( ...
        [-W/2 W/2], ...
        [-L/2 L/2], ...
        [-Z-z1 -Z], ...
        'GroundPlaneSensor', ...
        'component_ground', ...
        matCond);

    disp("Sensor full ground plane created.");

    % ==========================================================
    % 5. Cutter dimensions
    % ==========================================================
    % The cutters are taller than the copper thickness to make
    % boolean subtraction more robust.

    cutterHeight = 10*z1;
    cutterZmin   = -Z - 5*z1;
    % ==========================================================
    % Read physical gap / split reference value
    % ==========================================================
    % g is used as the physical gap / track width reference for
    % the local split bridges.

    g = app.gmmSpinner.Value;

    if isempty(g) || ~isfinite(g) || g <= 0
        g = 0.10;
        app.gmmSpinner.Value = g;
    end

    % ==========================================================
    % 6. Ring 1 cutter: outer minus inner
    % ==========================================================
    CST.addPolygonBlock( ...
        points1, ...
        [cutterHeight], ...
        'cut_gielis_ring1_outer', ...
        'component_sensor_cutters', ...
        matCond, ...
        'zmin', cutterZmin);

    CST.addPolygonBlock( ...
        points2, ...
        [cutterHeight], ...
        'cut_gielis_ring1_inner', ...
        'component_sensor_cutters', ...
        matCond, ...
        'zmin', cutterZmin);

    CST.subtractObject( ...
        'component_sensor_cutters:cut_gielis_ring1_outer', ...
        'component_sensor_cutters:cut_gielis_ring1_inner');

    disp("Gielis-CSRR ring 1 cutter created.");
    % ==========================================================
    % Ring 1 split bridge
    % ==========================================================
    % The split is created locally between the outer and inner
    % radius of the already-scaled ring 1.
    % This keeps the split proportional when Size 1 changes.

    Pout1 = points1(:,1:2);
    Pin1  = points2(:,1:2);

    % Remove repeated closing points if present
    if hypot(Pout1(1,1)-Pout1(end,1), Pout1(1,2)-Pout1(end,2)) < 1e-9
        Pout1(end,:) = [];
    end

    if hypot(Pin1(1,1)-Pin1(end,1), Pin1(1,2)-Pin1(end,2)) < 1e-9
        Pin1(end,:) = [];
    end

    % Reference center from the scaled outer contour
    cx1 = 0.5 * (min(Pout1(:,1)) + max(Pout1(:,1)));
    cy1 = 0.5 * (min(Pout1(:,2)) + max(Pout1(:,2)));

    % Top split position
    targetAngle1 = pi/2;

    thetaOut1 = atan2(Pout1(:,2) - cy1, Pout1(:,1) - cx1);

    diffOut1 = atan2( ...
        sin(thetaOut1 - targetAngle1), ...
        cos(thetaOut1 - targetAngle1));

    [~, idxOut1] = min(abs(diffOut1));

    pOuter1 = Pout1(idxOut1,:);

    % Find the inner point in the same radial direction
    thetaTarget1 = atan2(pOuter1(2) - cy1, pOuter1(1) - cx1);

    thetaIn1 = atan2(Pin1(:,2) - cy1, Pin1(:,1) - cx1);

    diffIn1 = atan2( ...
        sin(thetaIn1 - thetaTarget1), ...
        cos(thetaIn1 - thetaTarget1));

    [~, idxIn1] = min(abs(diffIn1));

    pInner1 = Pin1(idxIn1,:);

    radialVector1 = pOuter1 - pInner1;
    radialLength1 = norm(radialVector1);

    if radialLength1 <= 1e-9
        error("Invalid split radial length for ring 1.");
    end

    nSplit1 = radialVector1 / radialLength1;

    tSplit1 = [
        -nSplit1(2), ...
        nSplit1(1)];

    % Small offset beyond the slot boundaries
    splitOffset1 = max(0.02, min(0.10*radialLength1, 0.12*g));

    splitLength1 = radialLength1 + 2*splitOffset1;

    % Tangential bridge width.
    % It scales with the local slot thickness and with g.
    splitBridgeWidth1 = max(0.08, max(0.35*g, 0.35*radialLength1));
    splitBridgeWidth1 = min(splitBridgeWidth1, 0.85*radialLength1);

    pCenter1 = 0.5 * (pOuter1 + pInner1);

    bridge1 = [
        pCenter1 + 0.5*splitBridgeWidth1*tSplit1 + 0.5*splitLength1*nSplit1;
        pCenter1 - 0.5*splitBridgeWidth1*tSplit1 + 0.5*splitLength1*nSplit1;
        pCenter1 - 0.5*splitBridgeWidth1*tSplit1 - 0.5*splitLength1*nSplit1;
        pCenter1 + 0.5*splitBridgeWidth1*tSplit1 - 0.5*splitLength1*nSplit1;
        pCenter1 + 0.5*splitBridgeWidth1*tSplit1 + 0.5*splitLength1*nSplit1
        ];

    CST.addPolygonBlock( ...
        bridge1, ...
        [cutterHeight], ...
        'split_bridge_ring1', ...
        'component_sensor_cutters', ...
        matCond, ...
        'zmin', cutterZmin);

    CST.subtractObject( ...
        'component_sensor_cutters:cut_gielis_ring1_outer', ...
        'component_sensor_cutters:split_bridge_ring1');

    disp("Ring 1 local split bridge added.");

    % ==========================================================
    % 7. Ring 2 cutter: outer minus inner
    % ==========================================================
    CST.addPolygonBlock( ...
        points3, ...
        [cutterHeight], ...
        'cut_gielis_ring2_outer', ...
        'component_sensor_cutters', ...
        matCond, ...
        'zmin', cutterZmin);

    CST.addPolygonBlock( ...
        points4, ...
        [cutterHeight], ...
        'cut_gielis_ring2_inner', ...
        'component_sensor_cutters', ...
        matCond, ...
        'zmin', cutterZmin);

    CST.subtractObject( ...
        'component_sensor_cutters:cut_gielis_ring2_outer', ...
        'component_sensor_cutters:cut_gielis_ring2_inner');

    disp("Gielis-CSRR ring 2 cutter created.");
    % ==========================================================
    % Ring 2 split bridge
    % ==========================================================
    % The split is created locally between the outer and inner
    % radius of the already-scaled ring 2.
    % This keeps the split proportional when Size 2 changes.

    Pout2 = points3(:,1:2);
    Pin2  = points4(:,1:2);

    % Remove repeated closing points if present
    if hypot(Pout2(1,1)-Pout2(end,1), Pout2(1,2)-Pout2(end,2)) < 1e-9
        Pout2(end,:) = [];
    end

    if hypot(Pin2(1,1)-Pin2(end,1), Pin2(1,2)-Pin2(end,2)) < 1e-9
        Pin2(end,:) = [];
    end

    % Reference center from the scaled outer contour
    cx2 = 0.5 * (min(Pout2(:,1)) + max(Pout2(:,1)));
    cy2 = 0.5 * (min(Pout2(:,2)) + max(Pout2(:,2)));

    % Bottom split position
    targetAngle2 = -pi/2;

    thetaOut2 = atan2(Pout2(:,2) - cy2, Pout2(:,1) - cx2);

    diffOut2 = atan2( ...
        sin(thetaOut2 - targetAngle2), ...
        cos(thetaOut2 - targetAngle2));

    [~, idxOut2] = min(abs(diffOut2));

    pOuter2 = Pout2(idxOut2,:);

    % Find the inner point in the same radial direction
    thetaTarget2 = atan2(pOuter2(2) - cy2, pOuter2(1) - cx2);

    thetaIn2 = atan2(Pin2(:,2) - cy2, Pin2(:,1) - cx2);

    diffIn2 = atan2( ...
        sin(thetaIn2 - thetaTarget2), ...
        cos(thetaIn2 - thetaTarget2));

    [~, idxIn2] = min(abs(diffIn2));

    pInner2 = Pin2(idxIn2,:);

    radialVector2 = pOuter2 - pInner2;
    radialLength2 = norm(radialVector2);

    if radialLength2 <= 1e-9
        error("Invalid split radial length for ring 2.");
    end

    nSplit2 = radialVector2 / radialLength2;

    tSplit2 = [
        -nSplit2(2), ...
        nSplit2(1)];

    % Small offset beyond the slot boundaries
    splitOffset2 = max(0.02, min(0.10*radialLength2, 0.12*g));

    splitLength2 = radialLength2 + 2*splitOffset2;

    % Tangential bridge width.
    % It scales with the local slot thickness and with g.
    splitBridgeWidth2 = max(0.08, max(0.35*g, 0.35*radialLength2));
    splitBridgeWidth2 = min(splitBridgeWidth2, 0.85*radialLength2);

    pCenter2 = 0.5 * (pOuter2 + pInner2);

    bridge2 = [
        pCenter2 + 0.5*splitBridgeWidth2*tSplit2 + 0.5*splitLength2*nSplit2;
        pCenter2 - 0.5*splitBridgeWidth2*tSplit2 + 0.5*splitLength2*nSplit2;
        pCenter2 - 0.5*splitBridgeWidth2*tSplit2 - 0.5*splitLength2*nSplit2;
        pCenter2 + 0.5*splitBridgeWidth2*tSplit2 - 0.5*splitLength2*nSplit2;
        pCenter2 + 0.5*splitBridgeWidth2*tSplit2 + 0.5*splitLength2*nSplit2
        ];

    CST.addPolygonBlock( ...
        bridge2, ...
        [cutterHeight], ...
        'split_bridge_ring2', ...
        'component_sensor_cutters', ...
        matCond, ...
        'zmin', cutterZmin);

    CST.subtractObject( ...
        'component_sensor_cutters:cut_gielis_ring2_outer', ...
        'component_sensor_cutters:split_bridge_ring2');

    disp("Ring 2 local split bridge added.");

    % ==========================================================
    % 8. Subtract closed Gielis-CSRR slots from ground
    % ==========================================================
    CST.subtractObject( ...
        'component_ground:GroundPlaneSensor', ...
        'component_sensor_cutters:cut_gielis_ring1_outer');

    CST.subtractObject( ...
        'component_ground:GroundPlaneSensor', ...
        'component_sensor_cutters:cut_gielis_ring2_outer');

    disp("Closed Gielis-CSRR slots subtracted from the ground plane.");

    % ==========================================================
    % 9. Create uniform upper microstrip line
    % ==========================================================
    lineWidth = app.obtenerAnchoLineaSensor();

    if isempty(lineWidth) || ~isfinite(lineWidth) || lineWidth <= 0
        lineWidth = 3.0;
    end

    linePts = [
        -W/2, -lineWidth/2;
         W/2, -lineWidth/2;
         W/2,  lineWidth/2;
        -W/2,  lineWidth/2;
        -W/2, -lineWidth/2
    ];

    CST.addPolygonBlock( ...
        linePts, ...
        [z1], ...
        'MicrostripLine', ...
        'component_line', ...
        matCond, ...
        'zmin', 0);

    disp("Uniform microstrip line created.");
    disp("Line width = " + string(lineWidth) + " mm");

    % ==========================================================
    % 10. Final debug information
    % ==========================================================
    disp("Stable closed Gielis-CSRR sensor created successfully.");
    disp("Resonator width = " + string(resonatorWidth) + " mm");
    disp("Resonator height = " + string(resonatorHeight) + " mm");
    disp("Physical g value = " + string(app.gmmSpinner.Value) + " mm");

end
end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           app.SimulateandpostprocessButton.Enable = 'off';

    app.setEnglishUILabels();

    app.actualizarInterfazPorModo();
    app.actualizarControlesMaterial();
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Size changed function: SubstrateandmetalPanel
        function SubstrateandmetalPanelSizeChanged(app, event)

            
        end

        % Button pushed function: PreviewButton
        function PreviewButtonPushed(app, event)

    % ==========================================================
    % 1. Read Gielis parameters
    % ==========================================================
    a  = app.aSpinner.Value;
    b  = app.bSpinner.Value;
    m  = app.mSpinner.Value;
    n1 = app.n1Spinner.Value;
    n2 = app.n2Spinner.Value;
    n3 = app.n3Spinner.Value;

    % ==========================================================
    % 2. Basic validation
    % ==========================================================
    if a == 0 || b == 0 || n1 == 0
        uialert(app.UIFigure, ...
            "Parameters a, b, and n1 must be different from zero.", ...
            "Invalid Gielis parameters");
        return;
    end

    % ==========================================================
    % 3. Define angular period
    % ==========================================================
    [n, d] = rat(m);

    if mod(n, 2) == 0 || (a == b && n2 == n3)
        u = 2*d;
    else
        u = 4*d;
    end

    % ==========================================================
    % 4. Generate original Gielis curve
    % ==========================================================
    theta = linspace(0, u*pi, 8000);

    rho = ( ...
        abs(cos(m.*theta./4)./a).^n2 + ...
        abs(sin(m.*theta./4)./b).^n3 ...
        ).^(-1/n1);

    if any(~isfinite(rho))
        uialert(app.UIFigure, ...
            "The selected Gielis parameters generated invalid values. Adjust a, b, m, n1, n2, or n3.", ...
            "Invalid Gielis curve");
        return;
    end

    [xx_orig, yy_orig] = pol2cart(theta, rho);

   % ==========================================================
% 5. Select CST-compatible contour according to mode
% ==========================================================
% ==========================================================
% 5. Select CST-compatible contour according to mode
% ==========================================================
[Cbase, pgBase, ~] = app.seleccionarContornoBaseParaCST( ...
    xx_orig, yy_orig);

if isempty(Cbase) || area(pgBase) <= 0
    uialert(app.UIFigure, ...
        "A valid CST-compatible contour could not be obtained.", ...
        "Contour error");
    return;
end

xx = Cbase(:,1).';
yy = Cbase(:,2).';

% ==========================================================
% 6. Plot original curve, radial envelope reference, and selected contour
% ==========================================================
ax = app.UIAxes;
cla(ax);
hold(ax, 'on');

% Original Gielis curve
plot(ax, xx_orig, yy_orig, ':', ...
    'Color', [0.35 0.35 0.35], ...
    'LineWidth', 1.2, ...
    'DisplayName', 'Original Gielis curve');

% Radial envelope only as reference
[CenvPreview, pgEnvPreview] = app.getRadialOuterEnvelope( ...
    xx_orig, yy_orig, 360, 5);

if ~isempty(CenvPreview) && area(pgEnvPreview) > 0
    plot(ax, CenvPreview(:,1), CenvPreview(:,2), '--', ...
        'Color', [0.85 0.10 0.10], ...
        'LineWidth', 1.4, ...
        'DisplayName', 'Radial envelope reference');
end


hold(ax, 'off');

axis(ax, 'equal');
grid(ax, 'on');

xlabel(ax, 'X coordinate');
ylabel(ax, 'Y coordinate');
title(ax, 'Gielis curve preview');
legend(ax, 'show', 'Location', 'best');

drawnow;

% ==========================================================
% 7. Store data in the app
% ==========================================================
app.xx = xx;
app.yy = yy;

app.PP1 = xx;
app.PP2 = yy;

% Original curve only for visual reference
app.xx_original = xx_orig;
app.yy_original = yy_orig;

% Original polar data
app.rho = rho;
app.theta = theta;

% Store the contour that will actually be used in CST
app.contornosExternos = {Cbase};

% Internal holes are not used at this stage
app.contornosHuecos = {};

% Store selected polyshape
app.pgGielis = pgBase;
app.pgGielisExterior = pgBase;

    % ==========================================================
    % 8. Clear dependent concentric geometry
    % ==========================================================
    app.contornosConcentricos = {};
    app.contornosConcentricosBase = {};
    app.pgConcentricos = {};

    cla(app.UIAxes_2);
    title(app.UIAxes_2, 'Concentric structure preview');

    % ==========================================================
    % 9. Invalidate previous CST model
    % ==========================================================
    app.CSTActual = [];
    app.rutaCSTActual = "";
    app.nombreProyectoCST = "";
    app.modeloCSTConstruido = false;

    % ==========================================================
    % 10. Update checklist
    % ==========================================================
    app.setChecklist(true, false, false, false, false, false);
    app.SimulateandpostprocessButton.Enable = 'off';

    disp("Gielis curve preview generated successfully.");
    
        end

        % Button pushed function: GeneratestructureButton
        function GeneratestructureButtonPushed(app, event)

     % ==========================================================
    % 1. Fast gap validation
    % ==========================================================
    [gapOk, gapMsg] = app.validateGapBeforeGenerate();

    if ~gapOk
        uialert(app.UIFigure, ...
            gapMsg, ...
            "Gap too large");
        return;
    end

    % ==========================================================
    % 2. Validate base geometry
    % ==========================================================
    if isempty(app.pgGielisExterior) || ...
       area(app.pgGielisExterior) <= 0 || ...
       isempty(app.contornosExternos) || ...
       numel(app.contornosExternos) < 1

        uialert(app.UIFigure, ...
            "First generate the Gielis curve using the Preview button.", ...
            "No base geometry");
        return;
    end

    % ==========================================================
    % 3. Read parameters
    % ==========================================================
    W  = app.WsubstratemmSpinner.Value;
    L  = app.LsubstratemmSpinner.Value;
    Z  = app.hsubstratemmSpinner.Value;
    z1 = app.h1coppermmSpinner.Value;
    g  = app.gmmSpinner.Value;

    if W <= 0 || L <= 0 || Z <= 0 || z1 <= 0

        uialert(app.UIFigure, ...
            "Substrate and metal dimensions must be greater than zero.", ...
            "Invalid dimensions");
        return;

    end

    if g <= 0

        uialert(app.UIFigure, ...
            "The g value must be greater than zero.", ...
            "Invalid gap");
        return;

    end

    % ==========================================================
    % 4. Obtain and validate the base contour
    % ==========================================================
    Cbase = app.contornosExternos{1};

    if isempty(Cbase) || size(Cbase,1) < 3

        uialert(app.UIFigure, ...
            "There is no valid base contour. Press Preview before Generate.", ...
            "Invalid contour");
        return;

    end

    Cbase = Cbase(:,1:2);

    % Remove repeated final point, if present
    if hypot( ...
            Cbase(1,1) - Cbase(end,1), ...
            Cbase(1,2) - Cbase(end,2)) < 1e-9

        Cbase(end,:) = [];

    end

    if size(Cbase,1) < 3

        uialert(app.UIFigure, ...
            "The selected contour does not contain enough valid points.", ...
            "Invalid contour");
        return;

    end

    % Validate the original region
    pgBaseOriginal = polyshape( ...
        Cbase(:,1), ...
        Cbase(:,2), ...
        'Simplify', true, ...
        'KeepCollinearPoints', false);

    if area(pgBaseOriginal) <= 0

        uialert(app.UIFigure, ...
            "The selected contour is not a valid closed region.", ...
            "Invalid contour");
        return;

    end

    % Remove possible internal holes
    try
        pgBaseOriginal = rmholes(pgBaseOriginal);
        CbaseLimpio = app.getMainContourFromPolyshape(pgBaseOriginal);

        if ~isempty(CbaseLimpio) && size(CbaseLimpio,1) >= 3
            Cbase = CbaseLimpio(:,1:2);
        end
    catch
        % Continue using the original contour when rmholes
        % is unavailable or unnecessary.
    end

    % Remove repeated final point again after extracting boundary
    if hypot( ...
            Cbase(1,1) - Cbase(end,1), ...
            Cbase(1,2) - Cbase(end,2)) < 1e-9

        Cbase(end,:) = [];

    end
% ==========================================================
% 5. Validate resonator size without scaling it
% ==========================================================
% The substrate must not resize the resonator.
% The resonator size is controlled by the Gielis parameters and sliders.

substrateMargin = 0.5;

RbaseOriginal = max(hypot( ...
    Cbase(:,1), ...
    Cbase(:,2)));

RmaxAllowed = min(W, L)/2 - substrateMargin;

if isempty(RbaseOriginal) || ...
   ~isfinite(RbaseOriginal) || ...
   RbaseOriginal <= 0

    uialert(app.UIFigure, ...
        "The base figure does not have a valid size.", ...
        "Geometry error");
    return;

end

if RmaxAllowed <= 0

    uialert(app.UIFigure, ...
        "The substrate is too small. Increase W or L.", ...
        "Invalid substrate");
    return;

end

if RbaseOriginal > RmaxAllowed

    uialert(app.UIFigure, ...
        "The resonator is larger than the available substrate area." + newline + newline + ...
        "Current resonator radius = " + string(RbaseOriginal) + " mm" + newline + ...
        "Available substrate radius = " + string(RmaxAllowed) + " mm" + newline + newline + ...
        "Increase L/W substrate or reduce the Gielis size parameters.", ...
        "Resonator outside substrate");
    return;

end

    % ==========================================================
    % 6. Generate proportional concentric geometry
    % ==========================================================
    % g controls the slot width as a proportion of the reference
    % resonator radius.
    %
    % The spacing between both resonator rings is intentionally
    % smaller than before to allow stronger coupling.
    % ==========================================================

    gFactor = app.gmmSpinner.Value;

    if isempty(gFactor) || ~isfinite(gFactor) || gFactor <= 0
        gFactor = 0.08;
        app.gmmSpinner.Value = gFactor;
    end

    % More permissive range
    gFactor = max(0.01, min(gFactor, 0.60));
    app.gmmSpinner.Value = gFactor;

    % ==========================================================
    % Reference radius of the selected base contour
    % ==========================================================
    Rref = max(hypot( ...
        Cbase(:,1), ...
        Cbase(:,2)));

    if isempty(Rref) || ~isfinite(Rref) || Rref <= 0

        uialert(app.UIFigure, ...
            "The fitted base contour does not have a valid radius.", ...
            "Invalid geometry");

        return;

    end

    % ==========================================================
    % Inter-ring spacing control
    % ==========================================================
    % Smaller value = rings closer together.
    % Previous value was 0.75, which separated the rings too much.

    ringSpacingFactor = 0.15;

    % Keep a small but non-zero innermost contour
    minimumInnerScale = 0.03;

    % Maximum geometrically possible g:
    % s4 = 1 - 2*gFactor - ringSpacingFactor*gFactor
    maxGeometricGFactor = ...
        (1 - minimumInnerScale) / (2 + ringSpacingFactor);

    if gFactor > maxGeometricGFactor

        disp("The selected g value is very large for this geometry.");
        disp("It will be adjusted automatically to avoid invalid contours.");
        disp("Original g = " + string(gFactor));
        disp("Adjusted g = " + string(maxGeometricGFactor));

        gFactor = maxGeometricGFactor;
        app.gmmSpinner.Value = gFactor;

    end

    % ==========================================================
    % Generate scale factors
    % ==========================================================
    trackWidth = gFactor * Rref;
    ringGap    = ringSpacingFactor * trackWidth;

    s1 = 1.00;
    s2 = (Rref - trackWidth) / Rref;
    s3 = (Rref - trackWidth - ringGap) / Rref;
    s4 = (Rref - 2*trackWidth - ringGap) / Rref;

    scaleFactors = [s1, s2, s3, s4];

    if any(~isfinite(scaleFactors)) || any(scaleFactors <= 0)

        uialert(app.UIFigure, ...
            "The selected g value generated invalid concentric contours." + newline + newline + ...
            "Reduce g slightly or increase the resonator size.", ...
            "Invalid concentric geometry");

        return;

    end

    if any(~isfinite(scaleFactors)) || any(scaleFactors <= 0)

        uialert(app.UIFigure, ...
            "The proportional g factor generated invalid preview contours. Reduce g.", ...
            "Invalid geometry");

        return;

    end

    C1 = app.scaleContour(Cbase, s1);
    C2 = app.scaleContour(Cbase, s2);
    C3 = app.scaleContour(Cbase, s3);
    C4 = app.scaleContour(Cbase, s4);

    % ==========================================================
    % 7. Close the four contours
    % ==========================================================
    if hypot(C1(1,1)-C1(end,1), C1(1,2)-C1(end,2)) > 1e-9
        C1(end+1,:) = C1(1,:);
    end

    if hypot(C2(1,1)-C2(end,1), C2(1,2)-C2(end,2)) > 1e-9
        C2(end+1,:) = C2(1,:);
    end

    if hypot(C3(1,1)-C3(end,1), C3(1,2)-C3(end,2)) > 1e-9
        C3(end+1,:) = C3(1,:);
    end

    if hypot(C4(1,1)-C4(end,1), C4(1,2)-C4(end,2)) > 1e-9
        C4(end+1,:) = C4(1,:);
    end

    % ==========================================================
    % 8. Create reference polyshapes
    % ==========================================================
    pg1 = polyshape( ...
        C1(:,1), C1(:,2), ...
        'Simplify', true, ...
        'KeepCollinearPoints', false);

    pg2 = polyshape( ...
        C2(:,1), C2(:,2), ...
        'Simplify', true, ...
        'KeepCollinearPoints', false);

    pg3 = polyshape( ...
        C3(:,1), C3(:,2), ...
        'Simplify', true, ...
        'KeepCollinearPoints', false);

    pg4 = polyshape( ...
        C4(:,1), C4(:,2), ...
        'Simplify', true, ...
        'KeepCollinearPoints', false);

    if area(pg1) <= 0 || ...
       area(pg2) <= 0 || ...
       area(pg3) <= 0 || ...
       area(pg4) <= 0

        uialert(app.UIFigure, ...
            "The concentric geometry is not valid. Try a smoother contour or reduce g.", ...
            "Invalid concentric geometry");
        return;

    end

    % Verify correct nesting
    if area(pg2) >= area(pg1) || ...
       area(pg3) >= area(pg2) || ...
       area(pg4) >= area(pg3)

        uialert(app.UIFigure, ...
            "The concentric contours are not correctly nested. Reduce g or regenerate the base curve.", ...
            "Invalid concentric geometry");
        return;

    end

    % ==========================================================
    % 9. Store the base concentric contours
    % ==========================================================
    app.contornosConcentricosBase = {C1, C2, C3, C4};
    app.pgConcentricos = {pg1, pg2, pg3, pg4};

    % ==========================================================
    % 10. Initialize manual transformation controls
    % ==========================================================
    modoActual = app.obtenerModoActual();

    % Keep the external pair at full scale.
    % actualizarContornosDesdeControles will prevent Size 2
    % from becoming equal to or larger than Size 1.
    app.Size1Spinner.Value = 1;
    app.Size2Spinner.Value = 1;

    app.escalaFigura1 = app.Size1Spinner.Value;
    app.escalaFigura2 = app.Size2Spinner.Value;

    % ==========================================================
    % Configure transformations according to application mode
    % ==========================================================
    if modoActual == "Sensor"

        % Preserve sensor displacement and rotation
        app.anguloRotacionVista = app.RotationSlider.Value;

    else

        % Absorber and transmitter remain centered
        app.dxmmSpinner.Value = 0;
        app.dymmSpinner.Value = 0;

        app.anguloRotacionVista = 0;
        app.RotationSlider.Value = 0;

    end

    % ==========================================================
    % 11. Apply controls and redraw only once
    % ==========================================================
    app.actualizarContornosDesdeControles();
    app.redrawConcentricContours3D();


  app.escalaFigura1 = app.Size1Spinner.Value;
    app.escalaFigura2 = app.Size2Spinner.Value;

    % ==========================================================
    % 12. Store general geometry data
    % ==========================================================
    app.W = W;
    app.L = L;

    app.yp1 = [];
    app.yp2 = [];
    app.yp3 = [];

    % ==========================================================
    % 13. Invalidate any previous CST model
    % ==========================================================
    app.CSTActual = [];
    app.rutaCSTActual = "";
    app.nombreProyectoCST = "";
    app.modeloCSTConstruido = false;

    % ==========================================================
    % 14. Update checklist
    % ==========================================================
    app.setChecklist( ...
        true, ...    % Geometry generated
        false, ...   % Materials assigned
        false, ...   % Ports defined
        false, ...   % Boundaries configured
        false, ...   % Result defined
        false);      % CST model built

    app.SimulateandpostprocessButton.Enable = 'off';

    drawnow limitrate;

    disp("Concentric structure generated successfully.");


        end

        % Callback function
        function Size1SliderValueChanged(app, event)


    if isempty(app.contornosConcentricosBase) || numel(app.contornosConcentricosBase) < 4

        uialert(app.UIFigure, ...
            "First generate the concentric structures using the Generate structure button.", ...
            "No base contours");

        return;

    end

    app.actualizarContornosDesdeControles();
    app.redrawConcentricContours3D();
    app.invalidarModeloCST();


        end

        % Callback function
        function Size2SliderValueChanged(app, event)
% Value changed function: tamao2Slider


    if isempty(app.contornosConcentricosBase) || numel(app.contornosConcentricosBase) < 4

        uialert(app.UIFigure, ...
            "First generate the concentric structures using the Generate structure button.", ...
            "No base contours");

        return;

    end

    app.actualizarContornosDesdeControles();
    app.redrawConcentricContours3D();
    app.invalidarModeloCST();


        end

        % Callback function
        function DropDownValueChanged(app, event)

        end

        % Callback function
        function GroundplaneButtonGroupSelectionChanged(app, event)
            app.invalidarModeloCST();
            
        end

        % Selection changed function: ButtonGroup_2
        function ButtonGroup_2SelectionChanged(app, event)
             app.invalidarModeloCST();
            
        end

        % Button pushed function: BuildmodelinCSTButton
        function BuildmodelinCSTButtonPushed(app, event)

try

    disp("1. Build button pressed");

    % ==========================================================
    % 1. Validate base geometry
    % ==========================================================
    if isempty(app.contornosConcentricosBase) || numel(app.contornosConcentricosBase) < 4

        uialert(app.UIFigure, ...
            "First generate the Gielis curve and then the concentric structures.", ...
            "No contours available for export");

        return;

    end

    % Update size, displacement, and rotation before exporting to CST
    app.actualizarContornosDesdeControles();

    if isempty(app.contornosConcentricos) || numel(app.contornosConcentricos) < 4

        uialert(app.UIFigure, ...
            "The concentric contours could not be updated.", ...
            "Contour error");

        return;

    end

    disp("2. Contours found and updated");

    C1 = app.contornosConcentricos{1};
    C2 = app.contornosConcentricos{2};
    C3 = app.contornosConcentricos{3};
    C4 = app.contornosConcentricos{4};

    % ==========================================================
    % 2. Prepare points for CST
    % ==========================================================
    points1 = app.prepareCSTPolygon(C1);
    points2 = app.prepareCSTPolygon(C2);
    points3 = app.prepareCSTPolygon(C3);
    points4 = app.prepareCSTPolygon(C4);

    if isempty(points1) || isempty(points2) || isempty(points3) || isempty(points4)

        uialert(app.UIFigure, ...
            "One or more contours do not have enough valid points for CST.", ...
            "Contour error");

        return;

    end

    disp("3. Points prepared for CST");

    % ==========================================================
    % 3. Read dimensions and parameters
    % ==========================================================
    W  = app.WsubstratemmSpinner.Value;
    L  = app.LsubstratemmSpinner.Value;
    Z  = app.hsubstratemmSpinner.Value;
    z1 = app.h1coppermmSpinner.Value;

    dx = app.dxmmSpinner.Value;
    dy = app.dymmSpinner.Value;

    Er = app.ErSpinner.Value;

    if W <= 0 || L <= 0 || Z <= 0 || z1 <= 0 || Er <= 0

        uialert(app.UIFigure, ...
            "Substrate, metal, and dielectric parameters must be greater than zero.", ...
            "Invalid dimensions");

        return;

    end

    disp("4. Parameters read correctly");

    % ==========================================================
    % 3.1 Read application mode and interface selections
    % ==========================================================
    modoAplicacion = app.obtenerModoActual();

    puertosSeleccionados   = app.DropDown_2.Value;
    fronterasSeleccionadas = app.DropDown_3.Value;
    monitorSeleccionado    = app.DropDown_4.Value;

    disp("5. Current mode: " + string(modoAplicacion));

    % ==========================================================
    % 4. Create CST model in a safe temporary folder
    % ==========================================================
    rutaCST = fullfile(tempdir, 'GielisAppCST');

    if ~isfolder(rutaCST)

        [ok, msg] = mkdir(rutaCST);

        if ~ok

            uialert(app.UIFigure, ...
                "The CST folder could not be created:" + newline + ...
                string(rutaCST) + newline + newline + ...
                "Windows message:" + newline + string(msg), ...
                "CST folder error");

            return;

        end

    end

    % Check real write permission
    archivoPrueba = fullfile(rutaCST, 'permission_test.txt');

    fid = fopen(archivoPrueba, 'w');

    if fid == -1

        uialert(app.UIFigure, ...
            "MATLAB does not have write permission in:" + newline + ...
            string(rutaCST), ...
            "Permission error");

        return;

    end

    fprintf(fid, 'MATLAB-CST write test');
    fclose(fid);
    delete(archivoPrueba);

    disp("6. CST folder created or verified:");
    disp(rutaCST);

    % Unique project name to avoid blocking open files
    nombreProyecto = ['Simulation_' char(datetime("now", "Format", "yyyyMMdd_HHmmss")) '.cst'];

    disp("7. Creating CST session");

    CST = CST_MicrowaveStudio( ...
        rutaCST, ...
        nombreProyecto);

    disp("8. CST session created correctly");

    app.CSTActual = CST;
    app.rutaCSTActual = rutaCST;
    app.nombreProyectoCST = nombreProyecto;
    app.modeloCSTConstruido = false;

    % ==========================================================
    % 4.1 Basic CST configuration according to mode
    % ==========================================================
    app.configurarCSTBasicoSegunModo(CST, modoAplicacion);

    disp("9. Basic CST configuration applied");

    CST.addParameter('Er', Er);
    CST.addParameter('Mue', 1);
    CST.addParameter('W', W);
    CST.addParameter('L', L);
    CST.addParameter('z', Z);
    CST.addParameter('tCu', z1);
    CST.addParameter('dx', dx);
    CST.addParameter('dy', dy);

    disp("9. CST parameters created");

    % ==========================================================
    % 5. Configure materials
    % ==========================================================
    [matCond, matDiel] = app.configurarMaterialesCST(CST);

    disp("10. Materials configured");
    disp("Conductor material: " + string(matCond));
    disp("Dielectric material: " + string(matDiel));

    % ==========================================================
    % 6. Create substrate
    % ==========================================================
    CST.addBrick( ...
        [-W/2 W/2], ...
        [-L/2 L/2], ...
        [-Z 0], ...
        'Substrate', ...
        'Component1', ...
        matDiel);

    disp("11. Substrate created");

    % ==========================================================
    % 7. Optional ground plane
    % ==========================================================
    % Ground plane is automatically defined by the application mode.
usarTierra = (modoAplicacion == "Absorber") || (modoAplicacion == "Sensor");

    % In Sensor mode, the ground plane is created later as a
    % complementary ground plane inside construirSensorMicrostripGielis.
    if usarTierra && modoAplicacion ~= "Sensor"

        CST.addBrick( ...
            [-W/2 W/2], ...
            [-L/2 L/2], ...
            [-Z-z1 -Z], ...
            'GroundPlane', ...
            'Component1', ...
            matCond);

        disp("12. Ground plane created");

    else

        disp("12. Ground plane not created at this stage");

    end

    % ==========================================================
    % 8. Structure type: normal / complementary / sensor
    % ==========================================================
    selectedMode = app.ButtonGroup_2.SelectedObject.Text;
    usarComplementario = strcmpi(strtrim(selectedMode), 'Complementary');

    if modoAplicacion == "Sensor"

        % ======================================================
        % SENSOR:
        % Upper microstrip line + complementary Gielis ground
        % ======================================================
        disp("13. Building SENSOR mode");

        app.construirSensorMicrostripGielis( ...
            CST, points1, points2, points3, points4, W, L, Z, z1);

        disp("14. Sensor structure created");

    else

        if ~usarComplementario

            % ==================================================
            % NORMAL STRUCTURE
            % Outer ring = C1 - C2
            % Inner ring = C3 - C4
            % ==================================================
            disp("13. Building NORMAL structure");

            CST.addPolygonBlock( ...
                points1, ...
                [z1], ...
                'ring1_outer', ...
                'component2', ...
                matCond);

            disp("14. ring1_outer created");

            CST.addPolygonBlock( ...
                points2, ...
                [z1], ...
                'ring1_inner', ...
                'component2', ...
                matCond);

            disp("15. ring1_inner created");

            CST.addPolygonBlock( ...
                points3, ...
                [z1], ...
                'ring2_outer', ...
                'component2', ...
                matCond);

            disp("16. ring2_outer created");

            CST.addPolygonBlock( ...
                points4, ...
                [z1], ...
                'ring2_inner', ...
                'component2', ...
                matCond);

            disp("17. ring2_inner created");

            CST.subtractObject( ...
                'component2:ring1_outer', ...
                'component2:ring1_inner');

            disp("18. Outer ring created");

            CST.subtractObject( ...
                'component2:ring2_outer', ...
                'component2:ring2_inner');

            disp("19. Inner ring created");

        else

            % ==================================================
            % COMPLEMENTARY STRUCTURE
            % Full top copper layer with Gielis slots
            % ==================================================
            disp("13. Building COMPLEMENTARY structure");

            CST.addBrick( ...
                [-W/2 W/2], ...
                [-L/2 L/2], ...
                [0 z1], ...
                'ComplementaryTopCopper', ...
                'component2', ...
                matCond);

            disp("14. Complementary top copper layer created");

            cutterHeight = 3*z1;
            cutterZmin   = -z1;

            CST.addPolygonBlock( ...
                points1, ...
                [cutterHeight], ...
                'cut_ring1_outer', ...
                'component_cutters', ...
                matCond, ...
                'zmin', cutterZmin);

            disp("15. cut_ring1_outer created");

            CST.addPolygonBlock( ...
                points2, ...
                [cutterHeight], ...
                'cut_ring1_inner', ...
                'component_cutters', ...
                matCond, ...
                'zmin', cutterZmin);

            disp("16. cut_ring1_inner created");

            CST.subtractObject( ...
                'component_cutters:cut_ring1_outer', ...
                'component_cutters:cut_ring1_inner');

            disp("17. Outer slot created");

            CST.addPolygonBlock( ...
                points3, ...
                [cutterHeight], ...
                'cut_ring2_outer', ...
                'component_cutters', ...
                matCond, ...
                'zmin', cutterZmin);

            disp("18. cut_ring2_outer created");

            CST.addPolygonBlock( ...
                points4, ...
                [cutterHeight], ...
                'cut_ring2_inner', ...
                'component_cutters', ...
                matCond, ...
                'zmin', cutterZmin);

            disp("19. cut_ring2_inner created");

            CST.subtractObject( ...
                'component_cutters:cut_ring2_outer', ...
                'component_cutters:cut_ring2_inner');

            disp("20. Inner slot created");

            CST.subtractObject( ...
                'component2:ComplementaryTopCopper', ...
                'component_cutters:cut_ring1_outer');

            disp("21. Outer slot subtracted from the top copper layer");

            CST.subtractObject( ...
                'component2:ComplementaryTopCopper', ...
                'component_cutters:cut_ring2_outer');

            disp("22. Inner slot subtracted from the top copper layer");

        end

    end

    % ==========================================================
    % 8.1 Configure ports / excitation according to mode
    % ==========================================================
    disp("23. Configuring ports or excitation");

    app.configurarPuertosSegunModo(CST, modoAplicacion, W, L, Z, z1);

    disp("24. Ports or excitation configured");

    % ==========================================================
    % 9. Final message
    % ==========================================================
    if usarComplementario
        mensajeModo = "complementary structure on the top surface";
    else
        mensajeModo = "normal structure on the top surface";
    end

    if modoAplicacion == "Sensor"
        mensajeModo = "microstrip sensor with complementary Gielis ground plane";
    end

    if usarTierra
        mensajeTierra = "with ground plane";
    else
        mensajeTierra = "without ground plane";
    end

    % ==========================================================
    % 10. Update internal state and checklist
    % ==========================================================
    app.CSTActual = CST;
    app.rutaCSTActual = rutaCST;
    app.nombreProyectoCST = nombreProyecto;
    app.modeloCSTConstruido = true;

    app.setChecklist(true, true, true, true, true, true);

    uialert(app.UIFigure, ...
        "CST model built in mode: " + string(modoAplicacion) + newline + ...
        "Structure type: " + string(mensajeModo) + newline + ...
        "Ground plane: " + string(mensajeTierra) + newline + ...
        "Ports: " + string(puertosSeleccionados) + newline + ...
        "Boundaries: " + string(fronterasSeleccionadas) + newline + ...
        "Result: " + string(monitorSeleccionado) + newline + ...
        "dx = " + string(dx) + ", dy = " + string(dy) + ".", ...
        "Build completed");

    disp("25. Build completed successfully");

    % ==========================================================
% Update simulation checklist after Build
% ==========================================================
app.markStateAfterBuild();

catch ME

    disp("====================================================");
    disp("REAL ERROR IN BUILD BUTTON:");
    disp(ME.message);

    if ~isempty(ME.stack)
        disp("File:");
        disp(ME.stack(1).file);
        disp("Function:");
        disp(ME.stack(1).name);
        disp("Line:");
        disp(ME.stack(1).line);
    end

    disp("====================================================");

    mensajeError = "Build error:" + newline + newline + ...
                   string(ME.message);

    if ~isempty(ME.stack)
        mensajeError = mensajeError + newline + newline + ...
            "Function: " + string(ME.stack(1).name) + newline + ...
            "Line: " + string(ME.stack(1).line);
    end

    hayGeo = app.hayGeometriaCSTLista();
    app.setChecklist(hayGeo, false, false, false, false, false);

    uialert(app.UIFigure, ...
        mensajeError, ...
        "CST build error");

    app.setChecklist(true, false, false, false, false, false);
    app.SimulateandpostprocessButton.Enable = 'off';

end
        end

        % Callback function
        function dxmmEditFieldValueChanged(app, event)
            % Value changed function: dxEditField

    if isempty(app.contornosConcentricosBase) || numel(app.contornosConcentricosBase) < 4
        return;
    end

    app.actualizarContornosDesdeControles();
    app.redrawConcentricContours3D();


        end

        % Callback function
        function dymmEditFieldValueChanged(app, event)
         app.parametrosChanged(event);

    if isempty(app.contornosConcentricosBase) || numel(app.contornosConcentricosBase) < 4
        return;
    end

    app.actualizarContornosDesdeControles();
    app.redrawConcentricContours3D();
     
        end

        % Button down function: UIAxes_2
        function UIAxes_2ButtonDown(app, event)
            
        end

        % Selection changed function: ButtonGroup_3
        function ButtonGroup_3SelectionChanged(app, event)
            

     app.actualizarInterfazPorModo();

        end

        % Value changed function: FrequencyrangeSpinner
        function FrequencyrangeSpinnerValueChanged(app, event)
            app.parametrosChanged(event);
            
        end

        % Value changed function: RangodefrecuenciaSpinner_2
        function RangodefrecuenciaSpinner_2ValueChanged(app, event)
app.parametrosChanged(event);            
        end

        % Value changed function: DropDown
        function DropDownValueChanged2(app, event)
app.parametrosChanged(event);            
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
          app.parametrosChanged(event);
            
        end

        % Value changed function: DropDown_3
        function DropDown_3ValueChanged(app, event)
            app.parametrosChanged(event);
            
        end

        % Value changed function: DropDown_4
        function DropDown_4ValueChanged(app, event)
app.parametrosChanged(event);            
        end

        % Value changed function: BoundariesconfiguredCheckBox, 
        % ...and 4 other components
        function Modelo3DgeneradoCheckBoxValueChanged(app, event)
            hayGeo = app.hayGeometriaCSTLista();

    if app.modeloCSTConstruido
        app.setChecklist(true, true, true, true, true, true);
    else
        app.setChecklist(hayGeo, false, false, false, false, false);
    end
            
        end

        % Value changed function: ConductorDropDown
        function ConductorDropDownValueChanged(app, event)
            app.invalidarModeloCST();
            
        end

        % Value changed function: DielectricDropDown
        function DielectricDropDownValueChanged(app, event)
app.actualizarControlesMaterial();
        end

        % Button pushed function: SimulateandpostprocessButton
        function SimulateandpostprocessButtonPushed(app, event)
modoAplicacion = app.obtenerModoActual();

    try

        % ==========================================================
        % 1. Print current simulation status
        % ==========================================================
        disp("===== Status before simulation =====");
        disp("Current mode: " + string(modoAplicacion));
        disp("CST session is empty: " + string(isempty(app.CSTActual)));
        disp("CST model built: " + string(app.modeloCSTConstruido));
        disp("Ports defined: " + string(app.PortsdefinedCheckBox.Value));
        disp("Boundaries configured: " + string(app.BoundariesconfiguredCheckBox.Value));
        disp("Result defined: " + string(app.ResultdefinedCheckBox.Value));
        disp("===================================");

        % ==========================================================
        % 2. Validate CST model
        % ==========================================================
        if isempty(app.CSTActual) || ~app.modeloCSTConstruido

            uialert(app.UIFigure, ...
                "Build the CST model before running the simulation.", ...
                "Model not built");

            return;

        end

        % ==========================================================
        % 3. Validate excitation / ports
        % ==========================================================
        if ~app.PortsdefinedCheckBox.Value

            uialert(app.UIFigure, ...
                "The model does not have defined ports or excitation. Rebuild the model before running the simulation.", ...
                "No excitation");

            return;

        end

        % ==========================================================
        % 4. Validate boundaries
        % ==========================================================
        if ~app.BoundariesconfiguredCheckBox.Value

            uialert(app.UIFigure, ...
                "The model does not have configured boundary conditions. Rebuild the model before running the simulation.", ...
                "Boundaries not configured");

            return;

        end

        % ==========================================================
        % 5. Run CST simulation
        % ==========================================================
        CST = app.CSTActual;

        disp("Starting CST simulation...");
        CST.runSimulation();

        % ==========================================================
        % 6. Postprocess results according to the selected mode
        % ==========================================================
        disp("Simulation completed. Starting postprocessing...");
        app.postprocesarResultadosSegunModo(CST, modoAplicacion);

        disp("Postprocessing completed successfully.");

    catch ME

        % ==========================================================
        % 7. Error handling
        % ==========================================================
        mensaje = "The simulation or postprocessing could not be completed." + ...
                  newline + newline + ...
                  string(ME.message);

        if ~isempty(ME.stack)

            mensaje = mensaje + newline + newline + ...
                "Function: " + string(ME.stack(1).name) + newline + ...
                "Line: " + string(ME.stack(1).line);

        end

        uialert(app.UIFigure, ...
            mensaje, ...
            "Simulation error");

        disp(getReport(ME, 'extended'));

    end
        end

        % Value changed function: dielectrictanSpinner
        function dielectrictanSpinnerValueChanged(app, event)
app.invalidarModeloCST();            
        end

        % Value changed function: LinewidthmmSpinner
        function LinewidthmmSpinnerValueChanged(app, event)
            
    app.invalidarModeloCST();

    if app.obtenerModoActual() == "Sensor"

if ~isempty(app.contornosConcentricosBase) && numel(app.contornosConcentricosBase) >= 4

    % Recalculate the resonator position.
    % This is required when line width changes because the
    % resonator must keep a fixed clearance from the line edge.
    app.actualizarContornosDesdeControles();
    app.redrawConcentricContours3D();

    app.CSTActual = [];
    app.rutaCSTActual = "";
    app.nombreProyectoCST = "";
    app.modeloCSTConstruido = false;

    app.setChecklist(true, false, false, false, false, false);
    app.SimulateandpostprocessButton.Enable = 'off';
        end

    end

              
        end

        % Value changed function: aSpinner, bSpinner, mSpinner, n1Spinner, 
        % ...and 2 other components
        function mSpinnerValueChanged(app, event)
           % ==========================================================
    % Gielis parameters changed
    % ==========================================================
    % Changing a, b, m, n1, n2 or n3 invalidates the base curve,
    % the concentric geometry and the CST model.

    app.xx = [];
    app.yy = [];
    app.xx_original = [];
    app.yy_original = [];

    app.contornosExternos = {};
    app.contornosHuecos = {};
    app.contornosConcentricos = {};
    app.contornosConcentricosBase = {};
    app.pgConcentricos = {};

    app.pgGielis = polyshape();
    app.pgGielisExterior = polyshape();

    cla(app.UIAxes);
    grid(app.UIAxes, 'on');
    axis(app.UIAxes, 'equal');
    xlabel(app.UIAxes, 'X coordinate');
    ylabel(app.UIAxes, 'Y coordinate');
    title(app.UIAxes, 'Gielis curve preview');

    cla(app.UIAxes_2);
    grid(app.UIAxes_2, 'on');
    axis(app.UIAxes_2, 'equal');
    xlabel(app.UIAxes_2, 'X axis');
    ylabel(app.UIAxes_2, 'Y axis');
    title(app.UIAxes_2, 'Concentric structure preview');

    app.CSTActual = [];
    app.rutaCSTActual = "";
    app.nombreProyectoCST = "";
    app.modeloCSTConstruido = false;

    app.setChecklist(false, false, false, false, false, false);

            
        end

        % Callback function
        function bSpinnerValueChanged(app, event)
            value = app.bSpinner.Value;
            
        end

        % Value changed function: LsubstratemmSpinner, 
        % ...and 4 other components
        function gSpinnerValueChanged(app, event)

 src = [];

    try
        src = event.Source;
    catch
        src = app.gmmSpinner;
    end

    isGapChange = isequal(src, app.gmmSpinner);

    if isGapChange

        g = app.gmmSpinner.Value;

        if isempty(g) || ~isfinite(g) || g <= 0

            app.gmmSpinner.Value = 0.05;

            uialert(app.UIFigure, ...
                "The gap value must be greater than zero.", ...
                "Invalid gap");

            return;

        end

        if isempty(app.contornosExternos) || numel(app.contornosExternos) < 1 || ...
           isempty(app.pgGielisExterior) || area(app.pgGielisExterior) <= 0

            cla(app.UIAxes_2);
            grid(app.UIAxes_2, 'on');
            axis(app.UIAxes_2, 'equal');
            xlabel(app.UIAxes_2, 'X axis');
            ylabel(app.UIAxes_2, 'Y axis');
            title(app.UIAxes_2, 'Concentric structure preview');

            app.setChecklist(false, false, false, false, false, false);

            uialert(app.UIFigure, ...
                "First press Preview before changing the gap value.", ...
                "No base geometry");

            return;

        end

        app.actualizarContornosDesdeControles();

        if ~isempty(app.contornosConcentricos) && ...
           numel(app.contornosConcentricos) >= 4

            app.redrawConcentricContours3D();

            app.CSTActual = [];
            app.rutaCSTActual = "";
            app.nombreProyectoCST = "";
            app.modeloCSTConstruido = false;

            app.setChecklist(true, false, false, false, false, false);
            app.SimulateandpostprocessButton.Enable = 'off';

        else

            app.setChecklist(false, false, false, false, false, false);
            app.SimulateandpostprocessButton.Enable = 'off';

        end

        drawnow limitrate;
        return;

    end

    W  = app.WsubstratemmSpinner.Value;
    L  = app.LsubstratemmSpinner.Value;
    Z  = app.hsubstratemmSpinner.Value;
    z1 = app.h1coppermmSpinner.Value;

    if W <= 0 || L <= 0 || Z <= 0 || z1 <= 0

        uialert(app.UIFigure, ...
            "Substrate and metal dimensions must be greater than zero.", ...
            "Invalid dimensions");

        return;

    end

    if ~isempty(app.contornosExternos) && numel(app.contornosExternos) >= 1

        app.actualizarContornosDesdeControles();

        if ~isempty(app.contornosConcentricos) && ...
           numel(app.contornosConcentricos) >= 4

            app.redrawConcentricContours3D();

            app.CSTActual = [];
            app.rutaCSTActual = "";
            app.nombreProyectoCST = "";
            app.modeloCSTConstruido = false;

            app.setChecklist(true, false, false, false, false, false);
            app.SimulateandpostprocessButton.Enable = 'off';

        else

            cla(app.UIAxes_2);
            grid(app.UIAxes_2, 'on');
            axis(app.UIAxes_2, 'equal');
            xlabel(app.UIAxes_2, 'X axis');
            ylabel(app.UIAxes_2, 'Y axis');
            title(app.UIAxes_2, 'Concentric structure preview');

            app.setChecklist(false, false, false, false, false, false);
            app.SimulateandpostprocessButton.Enable = 'off';

        end

    else

        cla(app.UIAxes_2);
        grid(app.UIAxes_2, 'on');
        axis(app.UIAxes_2, 'equal');
        xlabel(app.UIAxes_2, 'X axis');
        ylabel(app.UIAxes_2, 'Y axis');
        title(app.UIAxes_2, 'Concentric structure preview');

        app.setChecklist(false, false, false, false, false, false);
        app.SimulateandpostprocessButton.Enable = 'off';

    end

    drawnow limitrate;

        end

        % Value changed function: ErSpinner
        function ErSpinnerValueChanged(app, event)
            app.invalidarModeloCST();            
        end

        % Value changed function: RotationSlider
        function RotationSliderValueChanged(app, event)
           

    app.anguloRotacionVista = app.RotationSlider.Value;

    if isempty(app.contornosConcentricosBase) || numel(app.contornosConcentricosBase) < 4
        return;
    end

    app.actualizarContornosDesdeControles();
    app.redrawConcentricContours3D();
    app.invalidarModeloCST();

            
        end

        % Value changed function: dxmmSpinner
        function dxmmSpinnerValueChanged(app, event)
             app.parametrosChanged(event);

    if isempty(app.contornosConcentricosBase) || numel(app.contornosConcentricosBase) < 4
        return;
    end

    app.actualizarContornosDesdeControles();
    app.redrawConcentricContours3D();
            
        end

        % Value changed function: dymmSpinner
        function dymmSpinnerValueChanged(app, event)
            app.parametrosChanged(event);

    if isempty(app.contornosConcentricosBase) || numel(app.contornosConcentricosBase) < 4
        return;
    end

    app.actualizarContornosDesdeControles();
    app.redrawConcentricContours3D();
            
        end

        % Value changing function: Size1Spinner
        function Size1SpinnerValueChanging(app, event)


 if isempty(app.contornosConcentricosBase) || numel(app.contornosConcentricosBase) < 4
        return;
    end

    minScale = 0.1;
    minDelta = 0.1;

    try
        newSize1 = event.Value;
    catch
        newSize1 = app.Size1Spinner.Value;
    end

    if isempty(newSize1) || ~isfinite(newSize1) || newSize1 < minScale + minDelta
        newSize1 = minScale + minDelta;
    end

    app.Size1Spinner.Value = newSize1;

    if app.Size2Spinner.Value >= app.Size1Spinner.Value
        app.Size2Spinner.Value = max(minScale, app.Size1Spinner.Value - minDelta);
    end

    app.actualizarContornosDesdeControles();
    app.redrawConcentricContours3D();
    app.invalidarModeloCST();
        end

        % Value changed function: Size2Spinner
        function Size2SpinnerValueChanged(app, event)

 if isempty(app.contornosConcentricosBase) || numel(app.contornosConcentricosBase) < 4
        return;
    end

    minScale = 0.1;
    minDelta = 0.1;

    size1 = app.Size1Spinner.Value;
    size2 = app.Size2Spinner.Value;

    if isempty(size2) || ~isfinite(size2) || size2 < minScale
        size2 = minScale;
    end

    if size2 >= size1
        size2 = max(minScale, size1 - minDelta);
    end

    app.Size2Spinner.Value = size2;

    app.actualizarContornosDesdeControles();
    app.redrawConcentricContours3D();
    app.invalidarModeloCST();
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GielisMetaCSTPanel = uipanel(app.UIFigure);
            app.GielisMetaCSTPanel.Title = 'GielisMeta-CST';
            app.SubstrateandmetalPanel = uipanel(app.GielisMetaCSTPanel);
            app.SubstrateandmetalPanel.Title = 'Substrate and metal';
            app.SubstrateandmetalPanel.SizeChangedFcn = createCallbackFcn(app, @SubstrateandmetalPanelSizeChanged, true);
            app.LsubstratemmSpinnerLabel = uilabel(app.SubstrateandmetalPanel);
            app.LsubstratemmSpinnerLabel.Text = 'L substrate (mm)';
            app.LsubstratemmSpinner = uispinner(app.SubstrateandmetalPanel);
            app.LsubstratemmSpinner.ValueChangedFcn = createCallbackFcn(app, @gSpinnerValueChanged, true);
            app.LsubstratemmSpinner.Value = 10;
            app.WsubstratemmSpinnerLabel = uilabel(app.SubstrateandmetalPanel);
            app.WsubstratemmSpinnerLabel.Text = 'W substrate (mm)';
            app.WsubstratemmSpinner = uispinner(app.SubstrateandmetalPanel);
            app.WsubstratemmSpinner.ValueChangedFcn = createCallbackFcn(app, @gSpinnerValueChanged, true);
            app.WsubstratemmSpinner.Value = 10;
            app.hsubstratemmSpinnerLabel = uilabel(app.SubstrateandmetalPanel);
            app.hsubstratemmSpinnerLabel.Text = 'h substrate (mm)';
            app.hsubstratemmSpinner = uispinner(app.SubstrateandmetalPanel);
            app.hsubstratemmSpinner.Step = 0.1;
            app.hsubstratemmSpinner.ValueChangedFcn = createCallbackFcn(app, @gSpinnerValueChanged, true);
            app.hsubstratemmSpinner.Value = 1.6;
            app.h1coppermmSpinnerLabel = uilabel(app.SubstrateandmetalPanel);
            app.h1coppermmSpinnerLabel.Text = 'h1 copper (mm)';
            app.h1coppermmSpinner = uispinner(app.SubstrateandmetalPanel);
            app.h1coppermmSpinner.Step = 0.001;
            app.h1coppermmSpinner.ValueChangedFcn = createCallbackFcn(app, @gSpinnerValueChanged, true);
            app.h1coppermmSpinner.Value = 0.035;
            app.gmmSpinnerLabel = uilabel(app.SubstrateandmetalPanel);
            app.gmmSpinnerLabel.Text = 'g (mm)';
            app.gmmSpinner = uispinner(app.SubstrateandmetalPanel);
            app.gmmSpinner.Step = 0.01;
            app.gmmSpinner.ValueChangedFcn = createCallbackFcn(app, @gSpinnerValueChanged, true);
            app.gmmSpinner.Value = 0.3;
            app.GielisgeometryPanel = uipanel(app.GielisMetaCSTPanel);
            app.GielisgeometryPanel.Title = 'Gielis geometry';
            app.aSpinnerLabel = uilabel(app.GielisgeometryPanel);
            app.aSpinnerLabel.Text = 'a';
            app.aSpinner = uispinner(app.GielisgeometryPanel);
            app.aSpinner.Step = 0.1;
            app.aSpinner.Limits = [0 Inf];
            app.aSpinner.ValueChangedFcn = createCallbackFcn(app, @mSpinnerValueChanged, true);
            app.aSpinner.Value = 2;
            app.bSpinnerLabel = uilabel(app.GielisgeometryPanel);
            app.bSpinnerLabel.Text = 'b';
            app.bSpinner = uispinner(app.GielisgeometryPanel);
            app.bSpinner.Step = 0.1;
            app.bSpinner.Limits = [0 Inf];
            app.bSpinner.ValueChangedFcn = createCallbackFcn(app, @mSpinnerValueChanged, true);
            app.bSpinner.Value = 2;
            app.mSpinnerLabel = uilabel(app.GielisgeometryPanel);
            app.mSpinnerLabel.Text = 'm';
            app.mSpinner = uispinner(app.GielisgeometryPanel);
            app.mSpinner.ValueChangedFcn = createCallbackFcn(app, @mSpinnerValueChanged, true);
            app.mSpinner.Value = 8;
            app.n1SpinnerLabel = uilabel(app.GielisgeometryPanel);
            app.n1SpinnerLabel.Text = 'n1';
            app.n1Spinner = uispinner(app.GielisgeometryPanel);
            app.n1Spinner.Step = 0.1;
            app.n1Spinner.ValueChangedFcn = createCallbackFcn(app, @mSpinnerValueChanged, true);
            app.n1Spinner.Value = 1;
            app.n2SpinnerLabel = uilabel(app.GielisgeometryPanel);
            app.n2SpinnerLabel.Text = 'n2';
            app.n2Spinner = uispinner(app.GielisgeometryPanel);
            app.n2Spinner.Step = 0.1;
            app.n2Spinner.ValueChangedFcn = createCallbackFcn(app, @mSpinnerValueChanged, true);
            app.n2Spinner.Value = 1;
            app.n3SpinnerLabel = uilabel(app.GielisgeometryPanel);
            app.n3SpinnerLabel.Text = 'n3';
            app.n3Spinner = uispinner(app.GielisgeometryPanel);
            app.n3Spinner.Step = 0.1;
            app.n3Spinner.ValueChangedFcn = createCallbackFcn(app, @mSpinnerValueChanged, true);
            app.n3Spinner.Value = 1;
            app.PreviewButton = uibutton(app.GielisMetaCSTPanel, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.PreviewButton.Text = 'Preview';
            app.GeneratestructureButton = uibutton(app.GielisMetaCSTPanel, 'push');
            app.GeneratestructureButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratestructureButtonPushed, true);
            app.GeneratestructureButton.Text = 'Generate structure';
            app.BuildmodelinCSTButton = uibutton(app.GielisMetaCSTPanel, 'push');
            app.BuildmodelinCSTButton.ButtonPushedFcn = createCallbackFcn(app, @BuildmodelinCSTButtonPushed, true);
            app.BuildmodelinCSTButton.Text = 'Build model in CST';
            app.CSTmaterialsPanel = uipanel(app.GielisMetaCSTPanel);
            app.CSTmaterialsPanel.Title = 'CST materials';
            app.ConductorDropDownLabel = uilabel(app.CSTmaterialsPanel);
            app.ConductorDropDownLabel.Text = 'Conductor';
            app.ConductorDropDown = uidropdown(app.CSTmaterialsPanel);
            app.ConductorDropDown.Items = {'Copper (annealed) ', 'PEC'};
            app.ConductorDropDown.ValueChangedFcn = createCallbackFcn(app, @ConductorDropDownValueChanged, true);
            app.ConductorDropDown.Value = 'Copper (annealed) ';
            app.DielectricDropDownLabel = uilabel(app.CSTmaterialsPanel);
            app.DielectricDropDownLabel.Text = 'Dielectric';
            app.DielectricDropDown = uidropdown(app.CSTmaterialsPanel);
            app.DielectricDropDown.Items = {'FR4_lossy', 'Rogers_RO4003C', 'Rogers_RO5880', 'Alumina', 'Custom_Er'};
            app.DielectricDropDown.ValueChangedFcn = createCallbackFcn(app, @DielectricDropDownValueChanged, true);
            app.DielectricDropDown.Value = 'FR4_lossy';
            app.dielectrictanSpinnerLabel = uilabel(app.CSTmaterialsPanel);
            app.dielectrictanSpinnerLabel.Text = 'dielectric tanδ';
            app.dielectrictanSpinner = uispinner(app.CSTmaterialsPanel);
            app.dielectrictanSpinner.Step = 0.001;
            app.dielectrictanSpinner.Limits = [0 0.2];
            app.dielectrictanSpinner.ValueChangedFcn = createCallbackFcn(app, @dielectrictanSpinnerValueChanged, true);
            app.dielectrictanSpinner.Value = 0.02;
            app.Panel = uipanel(app.GielisMetaCSTPanel);
            app.ButtonGroup_2 = uibuttongroup(app.Panel);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_2SelectionChanged, true);
            app.NormalButton = uiradiobutton(app.ButtonGroup_2);
            app.NormalButton.Text = 'Normal';
            app.NormalButton.Value = true;
            app.ComplementaryButton = uiradiobutton(app.ButtonGroup_2);
            app.ComplementaryButton.Text = 'Complementary';
            app.DielctricoDropDownLabel_2 = uilabel(app.Panel);
            app.DielctricoDropDownLabel_2.Text = {'Structure type'; ''};
            app.GieliscurvepreviewPanel = uipanel(app.GielisMetaCSTPanel);
            app.GieliscurvepreviewPanel.Title = 'Gielis curve preview';
            app.UIAxes = uiaxes(app.GieliscurvepreviewPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.ButtonGroup_3 = uibuttongroup(app.GielisMetaCSTPanel);
            app.ButtonGroup_3.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_3SelectionChanged, true);
            app.AbsorberButton = uiradiobutton(app.ButtonGroup_3);
            app.AbsorberButton.Text = 'Absorber';
            app.AbsorberButton.Value = true;
            app.TransmitterButton = uiradiobutton(app.ButtonGroup_3);
            app.TransmitterButton.Text = 'Transmitter';
            app.SensorButton = uiradiobutton(app.ButtonGroup_3);
            app.SensorButton.Text = 'Sensor';
            app.ModeconfigurationPanel = uipanel(app.GielisMetaCSTPanel);
            app.ModeconfigurationPanel.Title = 'Mode configuration';
            app.RangodefrecuenciaLabel = uilabel(app.ModeconfigurationPanel);
            app.RangodefrecuenciaLabel.Text = 'Frequency range';
            app.FrequencyrangeSpinner = uispinner(app.ModeconfigurationPanel);
            app.FrequencyrangeSpinner.ValueChangedFcn = createCallbackFcn(app, @FrequencyrangeSpinnerValueChanged, true);
            app.FrequencyrangeSpinner.Value = 1;
            app.RangodefrecuenciaSpinner_2 = uispinner(app.ModeconfigurationPanel);
            app.RangodefrecuenciaSpinner_2.ValueChangedFcn = createCallbackFcn(app, @RangodefrecuenciaSpinner_2ValueChanged, true);
            app.RangodefrecuenciaSpinner_2.Value = 8;
            app.DropDown = uidropdown(app.ModeconfigurationPanel);
            app.DropDown.Items = {'GHz', 'Mhz'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged2, true);
            app.DropDown.Value = 'GHz';
            app.RangodefrecuenciaSpinnerLabel_2 = uilabel(app.ModeconfigurationPanel);
            app.RangodefrecuenciaSpinnerLabel_2.Text = 'Ports';
            app.DropDown_2 = uidropdown(app.ModeconfigurationPanel);
            app.DropDown_2.Items = {'1 port - Reflection', '2 ports - Transmission', 'Normal incidence'};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.Value = '1 port - Reflection';
            app.RangodefrecuenciaSpinnerLabel_3 = uilabel(app.ModeconfigurationPanel);
            app.RangodefrecuenciaSpinnerLabel_3.Text = 'Boundaries';
            app.DropDown_3 = uidropdown(app.ModeconfigurationPanel);
            app.DropDown_3.Items = {'Open', 'Periodic', 'Electric/Magnetic'};
            app.DropDown_3.ValueChangedFcn = createCallbackFcn(app, @DropDown_3ValueChanged, true);
            app.DropDown_3.Value = 'Open';
            app.RangodefrecuenciaSpinnerLabel_4 = uilabel(app.ModeconfigurationPanel);
            app.RangodefrecuenciaSpinnerLabel_4.Text = 'Result';
            app.DropDown_4 = uidropdown(app.ModeconfigurationPanel);
            app.DropDown_4.Items = {'S-parameters', 'Absorption'};
            app.DropDown_4.ValueChangedFcn = createCallbackFcn(app, @DropDown_4ValueChanged, true);
            app.DropDown_4.Value = 'S-parameters';
            app.Label = uilabel(app.ModeconfigurationPanel);
            app.Label.Text = ' -';
            app.SimulationchecklistPanel = uipanel(app.GielisMetaCSTPanel);
            app.SimulationchecklistPanel.Title = 'Simulation checklist';
            app.GeometrygeneratedCheckBox = uicheckbox(app.SimulationchecklistPanel);
            app.GeometrygeneratedCheckBox.ValueChangedFcn = createCallbackFcn(app, @Modelo3DgeneradoCheckBoxValueChanged, true);
            app.GeometrygeneratedCheckBox.Text = 'Geometry generated';
            app.MaterialsassignedCheckBox = uicheckbox(app.SimulationchecklistPanel);
            app.MaterialsassignedCheckBox.ValueChangedFcn = createCallbackFcn(app, @Modelo3DgeneradoCheckBoxValueChanged, true);
            app.MaterialsassignedCheckBox.Text = 'Materials assigned';
            app.PortsdefinedCheckBox = uicheckbox(app.SimulationchecklistPanel);
            app.PortsdefinedCheckBox.ValueChangedFcn = createCallbackFcn(app, @Modelo3DgeneradoCheckBoxValueChanged, true);
            app.PortsdefinedCheckBox.Text = 'Ports defined';
            app.BoundariesconfiguredCheckBox = uicheckbox(app.SimulationchecklistPanel);
            app.BoundariesconfiguredCheckBox.ValueChangedFcn = createCallbackFcn(app, @Modelo3DgeneradoCheckBoxValueChanged, true);
            app.BoundariesconfiguredCheckBox.Text = 'Boundaries configured';
            app.ResultdefinedCheckBox = uicheckbox(app.SimulationchecklistPanel);
            app.ResultdefinedCheckBox.ValueChangedFcn = createCallbackFcn(app, @Modelo3DgeneradoCheckBoxValueChanged, true);
            app.ResultdefinedCheckBox.Text = 'Result defined';
            app.ConcentricstructurePanel = uipanel(app.GielisMetaCSTPanel);
            app.ConcentricstructurePanel.Title = 'Concentric structure';
            app.UIAxes_2 = uiaxes(app.ConcentricstructurePanel);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.ButtonDownFcn = createCallbackFcn(app, @UIAxes_2ButtonDown, true);
            app.RotationSliderLabel = uilabel(app.ConcentricstructurePanel);
            app.RotationSliderLabel.Text = 'Rotation';
            app.RotationSlider = uislider(app.ConcentricstructurePanel);
            app.RotationSlider.Limits = [0 360];
            app.RotationSlider.MajorTicks = [0 90 180 270 360];
            app.RotationSlider.ValueChangedFcn = createCallbackFcn(app, @RotationSliderValueChanged, true);
            app.RotationSlider.MinorTicks = [0 15 30 45 60 75 90 105 120 135 150 165 180 195 210 225 240 255 270 285 300 315 330 345 360];
            app.dxmmSpinnerLabel = uilabel(app.ConcentricstructurePanel);
            app.dxmmSpinnerLabel.Text = 'dx (mm)';
            app.dxmmSpinner = uispinner(app.ConcentricstructurePanel);
            app.dxmmSpinner.Step = 0.1;
            app.dxmmSpinner.ValueChangedFcn = createCallbackFcn(app, @dxmmSpinnerValueChanged, true);
            app.dymmSpinnerLabel = uilabel(app.ConcentricstructurePanel);
            app.dymmSpinnerLabel.Text = 'dy (mm)';
            app.dymmSpinner = uispinner(app.ConcentricstructurePanel);
            app.dymmSpinner.Step = 0.1;
            app.dymmSpinner.ValueChangedFcn = createCallbackFcn(app, @dymmSpinnerValueChanged, true);
            app.Size1SpinnerLabel = uilabel(app.ConcentricstructurePanel);
            app.Size1SpinnerLabel.Text = 'Size 1';
            app.Size1Spinner = uispinner(app.ConcentricstructurePanel);
            app.Size1Spinner.Step = 0.1;
            app.Size1Spinner.ValueChangingFcn = createCallbackFcn(app, @Size1SpinnerValueChanging, true);
            app.Size1Spinner.Limits = [0 Inf];
            app.Size1Spinner.Value = 1;
            app.Size2SpinnerLabel = uilabel(app.ConcentricstructurePanel);
            app.Size2SpinnerLabel.Text = 'Size 2';
            app.Size2Spinner = uispinner(app.ConcentricstructurePanel);
            app.Size2Spinner.Step = 0.1;
            app.Size2Spinner.Limits = [0 Inf];
            app.Size2Spinner.ValueChangedFcn = createCallbackFcn(app, @Size2SpinnerValueChanged, true);
            app.Size2Spinner.Value = 0.8;
            app.SimulateandpostprocessButton = uibutton(app.GielisMetaCSTPanel, 'push');
            app.SimulateandpostprocessButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateandpostprocessButtonPushed, true);
            app.SimulateandpostprocessButton.Text = 'Simulate and postprocess';
            app.LinewidthmmSpinnerLabel = uilabel(app.GielisMetaCSTPanel);
            app.LinewidthmmSpinnerLabel.Text = 'Line width (mm)';
            app.LinewidthmmSpinner = uispinner(app.GielisMetaCSTPanel);
            app.LinewidthmmSpinner.Step = 0.1;
            app.LinewidthmmSpinner.Limits = [0.1 10];
            app.LinewidthmmSpinner.ValueChangedFcn = createCallbackFcn(app, @LinewidthmmSpinnerValueChanged, true);
            app.LinewidthmmSpinner.Value = 3;
            app.ErSpinnerLabel = uilabel(app.GielisMetaCSTPanel);
            app.ErSpinnerLabel.Text = 'Er';
            app.ErSpinner = uispinner(app.GielisMetaCSTPanel);
            app.ErSpinner.Step = 0.1;
            app.ErSpinner.ValueChangedFcn = createCallbackFcn(app, @ErSpinnerValueChanged, true);
            app.ErSpinner.Value = 4.3;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GielisMetaCST

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end